classdef RotaryDryerDesigner < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Toolbar                         matlab.ui.container.Toolbar
        PushTool                        matlab.ui.container.toolbar.PushTool
        PushTool2                       matlab.ui.container.toolbar.PushTool
        PushTool3                       matlab.ui.container.toolbar.PushTool
        PushTool5                       matlab.ui.container.toolbar.PushTool
        PushTool4                       matlab.ui.container.toolbar.PushTool
        TabGroup                        matlab.ui.container.TabGroup
        InputTab                        matlab.ui.container.Tab
        Image2                          matlab.ui.control.Image
        Image                           matlab.ui.control.Image
        OperatingConditionsPanel        matlab.ui.container.Panel
        FuelTypeDropDown                matlab.ui.control.DropDown
        FuelselectionLabel_2            matlab.ui.control.Label
        FillingDegree                   matlab.ui.control.NumericEditField
        FillingDegreeLabel              matlab.ui.control.Label
        Vgas_teo                        matlab.ui.control.Spinner
        GasVelocityLabel                matlab.ui.control.Label
        PeripheralVelocity              matlab.ui.control.Spinner
        PeripheralvelocityLabel         matlab.ui.control.Label
        msLabel_2                       matlab.ui.control.Label
        msLabel                         matlab.ui.control.Label
        phiRet                          matlab.ui.control.NumericEditField
        FgasLabel_3                     matlab.ui.control.Label
        fractionalheatloss              matlab.ui.control.NumericEditField
        FgasLabel_2                     matlab.ui.control.Label
        InputPanel                      matlab.ui.container.Panel
        Label_4                         matlab.ui.control.Label
        FinalMouisturetarget            matlab.ui.control.NumericEditField
        TargetlMouistureLabel           matlab.ui.control.Label
        CLabel_4                        matlab.ui.control.Label
        MaxSolidTemperature             matlab.ui.control.NumericEditField
        MaximumsolidtemperatureLabel    matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        GasInletTemperature             matlab.ui.control.NumericEditField
        MaxinletgasTemperatLabel        matlab.ui.control.Label
        SolidTemperature                matlab.ui.control.NumericEditField
        InitialsolidtemperatureLabel    matlab.ui.control.Label
        InitialMouisture                matlab.ui.control.NumericEditField
        InitialmoistureLabel            matlab.ui.control.Label
        Free                            matlab.ui.control.NumericEditField
        FeedrateLabel                   matlab.ui.control.Label
        thLabel                         matlab.ui.control.Label
        CLabel_3                        matlab.ui.control.Label
        CLabel                          matlab.ui.control.Label
        PropertiesTab                   matlab.ui.container.Tab
        AmbientairPanel                 matlab.ui.container.Panel
        kgmLabel_3                      matlab.ui.control.Label
        kJkgLabel_2                     matlab.ui.control.Label
        JkgKLabel_3                     matlab.ui.control.Label
        psicromety                      matlab.ui.control.Button
        airProps_cal                    matlab.ui.control.Button
        WmKLabel_3                      matlab.ui.control.Label
        h_aire                          matlab.ui.control.NumericEditField
        SolidthermalconductivityLabel_6  matlab.ui.control.Label
        w_aire                          matlab.ui.control.NumericEditField
        SolidthermalconductivityLabel_5  matlab.ui.control.Label
        kgkgLabel                       matlab.ui.control.Label
        mLabel_2                        matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        CLabel_6                        matlab.ui.control.Label
        den_aire                        matlab.ui.control.NumericEditField
        SolidthermalconductivityLabel_4  matlab.ui.control.Label
        K_aire                          matlab.ui.control.NumericEditField
        SolidthermalconductivityLabel_3  matlab.ui.control.Label
        Cp_aire_box                     matlab.ui.control.NumericEditField
        SolidspecificheatcapacityLabel_12  matlab.ui.control.Label
        AmbientRelativeHumidity         matlab.ui.control.NumericEditField
        RelativeHumidityLabel_3         matlab.ui.control.Label
        Altitude                        matlab.ui.control.NumericEditField
        AltitudeEditFieldLabel          matlab.ui.control.Label
        AmbientTemperature              matlab.ui.control.NumericEditField
        AmbientTemperatureLabel         matlab.ui.control.Label
        DryinggaspropertiesPanel        matlab.ui.container.Panel
        Label_5                         matlab.ui.control.Label
        Tm_gas                          matlab.ui.control.NumericEditField
        SolidthermalconductivityLabel_7  matlab.ui.control.Label
        CLabel_5                        matlab.ui.control.Label
        PasLabel                        matlab.ui.control.Label
        Gas_Props_cal                   matlab.ui.control.Button
        viscosidad_D_gas                matlab.ui.control.NumericEditField
        SolidspecificheatcapacityLabel_13  matlab.ui.control.Label
        kgmLabel_2                      matlab.ui.control.Label
        Density_gas                     matlab.ui.control.NumericEditField
        SolidspecificheatcapacityLabel_11  matlab.ui.control.Label
        Egas                            matlab.ui.control.NumericEditField
        SolidspecificheatcapacityLabel_10  matlab.ui.control.Label
        JkgKLabel_2                     matlab.ui.control.Label
        WmKLabel_2                      matlab.ui.control.Label
        Kgas                            matlab.ui.control.NumericEditField
        SolidthermalconductivityLabel_2  matlab.ui.control.Label
        Cpgas                           matlab.ui.control.NumericEditField
        SolidspecificheatcapacityLabel_9  matlab.ui.control.Label
        SolidpropertiesPanel            matlab.ui.container.Panel
        Label_6                         matlab.ui.control.Label
        SaveButton                      matlab.ui.control.Button
        DeleteButton                    matlab.ui.control.Button
        mLabel_4                        matlab.ui.control.Label
        kgmLabel                        matlab.ui.control.Label
        ParticleDiameter                matlab.ui.control.NumericEditField
        SolidspecificheatcapacityLabel_4  matlab.ui.control.Label
        TrueDensity                     matlab.ui.control.NumericEditField
        SolidspecificheatcapacityLabel_3  matlab.ui.control.Label
        Es                              matlab.ui.control.NumericEditField
        SolidspecificheatcapacityLabel_2  matlab.ui.control.Label
        JkgKLabel                       matlab.ui.control.Label
        WmKLabel                        matlab.ui.control.Label
        kgm3Label                       matlab.ui.control.Label
        Ksolid                          matlab.ui.control.NumericEditField
        SolidthermalconductivityLabel   matlab.ui.control.Label
        Cpsolid                         matlab.ui.control.NumericEditField
        SolidspecificheatcapacityLabel  matlab.ui.control.Label
        Bulksdensity                    matlab.ui.control.NumericEditField
        BulksoliddensityLabel           matlab.ui.control.Label
        MaterialTree                    matlab.ui.container.Tree
        MaterialNode                    matlab.ui.container.TreeNode
        Lateritic_oreNode               matlab.ui.container.TreeNode
        KineticModelFittingTab          matlab.ui.container.Tab
        KineticTable_2                  matlab.ui.control.Table
        AddrowButton                    matlab.ui.control.Button
        FitArrheniusButton              matlab.ui.control.Button
        KineticTable                    matlab.ui.control.Table
        FitKineticsButton               matlab.ui.control.Button
        Impor_data                      matlab.ui.control.Button
        KineticEquationLabel            matlab.ui.control.Label
        paramet_Heq                     matlab.ui.control.NumericEditField
        BulksoliddensityLabel_2         matlab.ui.control.Label
        ModelDropDown                   matlab.ui.control.DropDown
        paramet_E                       matlab.ui.control.NumericEditField
        BulksoliddensityLabel_3         matlab.ui.control.Label
        paramet_A                       matlab.ui.control.NumericEditField
        SolidspecificheatcapacityLabel_15  matlab.ui.control.Label
        paramet_R                       matlab.ui.control.NumericEditField
        SolidspecificheatcapacityLabel_14  matlab.ui.control.Label
        ArrheniusAxes                   matlab.ui.control.UIAxes
        ResultsTab                      matlab.ui.container.Tab
        StatusPanel                     matlab.ui.container.Panel
        PhysicalvalidityLamp            matlab.ui.control.Lamp
        PhysicalvalidityLampLabel       matlab.ui.control.Label
        TargetreachedLamp               matlab.ui.control.Lamp
        TargetreachedLampLabel          matlab.ui.control.Label
        SolverstatusLamp                matlab.ui.control.Lamp
        SolverstatusLampLabel           matlab.ui.control.Label
        SolverparametersPanel           matlab.ui.container.Panel
        SolveButton                     matlab.ui.control.Button
        Tolerance                       matlab.ui.control.NumericEditField
        ToleranceLabel                  matlab.ui.control.Label
        Lm_ax                           matlab.ui.control.NumericEditField
        LmaxLabel                       matlab.ui.control.Label
        Stip                            matlab.ui.control.NumericEditField
        RelativeHumidityLabel_4         matlab.ui.control.Label
        DesignparametersPanel           matlab.ui.container.Panel
        DesignButton                    matlab.ui.control.Button
        mLabel_3                        matlab.ui.control.Label
        RateMgs                         matlab.ui.control.NumericEditField
        FgasLabel_4                     matlab.ui.control.Label
        kgsLabel                        matlab.ui.control.Label
        Diametro_test                   matlab.ui.control.NumericEditField
        DinameterLabel                  matlab.ui.control.Label
        ButtonGroup                     matlab.ui.container.ButtonGroup
        exporttable                     matlab.ui.control.Button
        exportfig                       matlab.ui.control.Button
        T_x_M                           matlab.ui.control.RadioButton
        UITableResults                  matlab.ui.control.Table
        HumidityButton                  matlab.ui.control.RadioButton
        TempButton                      matlab.ui.control.RadioButton
        UIAxes                          matlab.ui.control.UIAxes
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end


properties (Access = private)
    SolverResults = struct()
        ImportedKineticData = table()
end


    methods (Access = private)


        function updateStatusLamps(app, solverOK, targetOK, validityWarning)

            green  = [0 0.7 0];
            red    = [0.85 0 0];
            yellow = [0.93 0.69 0.13];
            gray   = [0.7 0.7 0.7];

            % Solver status
            if isempty(solverOK)
                app.SolverstatusLamp.Color = gray;
            elseif solverOK
                app.SolverstatusLamp.Color = green;
            else
                app.SolverstatusLamp.Color = red;
            end

            % Target reached
            if isempty(targetOK)
                app.TargetreachedLamp.Color = gray;
            elseif targetOK
                app.TargetreachedLamp.Color = green;
            else
                app.TargetreachedLamp.Color = red;
            end

            % Physical validity
            if isempty(validityWarning)
                app.PhysicalvalidityLamp.Color = gray;
            elseif validityWarning
                app.PhysicalvalidityLamp.Color = yellow;
            else
                app.PhysicalvalidityLamp.Color = green;
            end

        end


        function loadMaterialsFromLIB(app)


            appPath = fileparts(mfilename('fullpath'));
         projectRoot = fileparts(appPath);
           libPath = fullfile(projectRoot, 'materials');

            if ~exist(libPath, 'dir')
                return
            end

            files = dir(fullfile(libPath, '*.txt'));

            for k = 1:numel(files)
                [~, name, ~] = fileparts(files(k).name);
                app.addMaterialToTree(name);
            end
        end


        function loadMaterial(app, materialName)

               appPath = fileparts(mfilename('fullpath'));
            projectRoot = fileparts(appPath);
          filePath = fullfile(projectRoot,'materials',[materialName '.txt']);

            if ~isfile(filePath)
                uialert(app.UIFigure,'Material file not found.','Error');
                return
            end

            try
                raw = fileread(filePath);
                data = jsondecode(raw);
            catch
                uialert(app.UIFigure,'Invalid JSON format.','Error');
                return
            end

            props = data.PhysicalProperties;

            app.Ksolid.Value            = props.ThermalConductivity_W_mK;
            app.Cpsolid.Value           = props.SpecificHeat_J_kgK;
            app.TrueDensity.Value       = props.TrueDensity_kg_m3;
            app.Bulksdensity.Value      = props.BulkDensity_kg_m3;
            app.Es.Value                = props.Emissivity;
            app.ParticleDiameter.Value  = props.ParticleDiameter_m;

            kin = data.KineticProperties;

            app.paramet_A.Value   = kin.A_preExponential_1_s;
            app.paramet_E.Value   = kin.ActivationEnergy_J_mol;
            app.paramet_Heq.Value = kin.EquilibriumMoisture_kg_kg;
            app.paramet_R.Value   = 8.314;
        end


        function s = valueToString(app, v)

            if ischar(v)
                s = v;
                return;
            end

            if isstring(v)
                s = char(v);
                return;
            end

            if isnumeric(v)
                if isempty(v)
                    s = '[]';
                elseif isscalar(v)
                    if isfinite(v)
                        s = sprintf('%.6g', v);
                    else
                        s = 'NaN';
                    end
                else
                    sz = size(v);
                    if numel(sz) < 2
                        s = sprintf('[%d numeric]', sz(1));
                    else
                        s = sprintf('[%dx%d numeric]', sz(1), sz(2));
                    end
                end
                return;
            end

            if islogical(v)
                s = mat2str(v);
                return;
            end

            if iscell(v)
                s = '[cell]';
                return;
            end

            s = '[unsupported]';
        end


        function txt = safeText(app, txt)

            if isstring(txt)
                txt = char(txt);
            end

            if isempty(txt)
                txt = '';
            end
        end


        function out = truncateText(app, txt, maxLen)

            txt = app.safeText(txt);

            if numel(txt) <= maxLen
                out = txt;
            else
                out = [txt(1:maxLen-3) '...'];
            end
        end


        function S = collectReportData(app)

            S.ReportTitle = 'RotaryDryerDesigner Report';
            S.DateString  = datestr(now, 'yyyy-mm-dd HH:MM:SS');

            S.InputData = {
                'Feed rate',                  app.Free.Value,                 't/h'
                'Initial moisture', app.InitialMouisture.Value,               '% d.b.'
                'Target moisture',  app.FinalMouisturetarget.Value,           '% d.b.'
                'Solid inlet temperature',    app.SolidTemperature.Value,     '°C'
                'Gas inlet temperature',      app.GasInletTemperature.Value,  '°C'
                'Maximum solid temperature',  app.MaxSolidTemperature.Value,  '°C'
                };

            S.OperatingData = {
                'Fractional heat loss',       app.fractionalheatloss.Value,   '-'
                'Holdup without gas flow',    app.phiRet.Value,               '-'
                'Design gas velocity',        app.Vgas_teo.Value,             'm/s'
                'Peripheral velocity',        app.PeripheralVelocity.Value,   'm/s'
                'Filling degree',             app.FillingDegree.Value,        '-'
                };

            S.AmbientData = {
                'Ambient temperature',        app.AmbientTemperature.Value,       '°C'
                'Relative humidity',          app.AmbientRelativeHumidity.Value,  '%'
                'Altitude',                   app.Altitude.Value,                 'm'
                };

            S.SolidProps = {
                'Thermal conductivity',       app.Ksolid.Value,              'W/m·K'
                'Specific heat capacity',     app.Cpsolid.Value,             'J/kg·K'
                'True density',               app.TrueDensity.Value,         'kg/m^3'
                'Bulk density',               app.Bulksdensity.Value,        'kg/m^3'
                'Emissivity',                 app.Es.Value,                  '-'
                'Mean particle size',         app.ParticleDiameter.Value,    'um'
                };

            S.AirProps = {
                'Specific heat capacity',     app.Cp_aire_box.Value,         'J/kg·K'
                'Thermal conductivity',       app.K_aire.Value,              'W/m·K'
                'Density',                    app.den_aire.Value,            'kg/m^3'
                'Humidity ratio w',           app.w_aire.Value,              'kg/kg dry air'
                'Air enthalpy h',             app.h_aire.Value,              '-'
                };

            S.GasProps = {
                'Specific heat capacity',     app.Cpgas.Value,               'J/kg·K'
                'Thermal conductivity',       app.Kgas.Value,                'W/m·K'
                'Density',                    app.Density_gas.Value,         'kg/m^3'
                'Dynamic viscosity',          app.viscosidad_D_gas.Value,    'Pa·s'
                'Emissivity',                 app.Egas.Value,                '-'
                };

            S.KineticData = {
                'Selected model',             app.ModelDropDown.Value,       ''
                'E parameter',                app.paramet_E.Value,           'J/mol'
                'R parameter',                app.paramet_R.Value,           'J/mol·K'
                'A parameter',                app.paramet_A.Value,           '1/s'
                'Equilibrium moisture', app.paramet_Heq.Value,               'kg/kg d.b.'
                };

            S.ResultPanel = {
                'Diameter',                   app.Diametro_test.Value,       'm'
                'Mass gas flow',              app.RateMgs.Value,             'kg/s'
                'Axial step',                 app.Stip.Value,                'm'
                'Maximum axial length',       app.Lm_ax.Value,               'm'
                'Moisture tolerance',         app.Tolerance.Value,           '-'
                };

            S.ResultsTable = app.UITableResults.Data;
        end


        function addMaterialToTree(app, materialNodeName)
            parentNode = [];

            for k = 1:numel(app.MaterialTree.Children)
                if strcmp(app.MaterialTree.Children(k).Text, 'Material')
                    parentNode = app.MaterialTree.Children(k);
                    break
                end
            end

            if isempty(parentNode)
                uialert(app.UIFigure, ...
                    'No se encontró el nodo carpeta "Material" en el árbol.', ...
                    'Árbol');
                return
            end

            for k = 1:numel(parentNode.Children)
                if strcmp(parentNode.Children(k).Text, materialNodeName)
                    return
                end
            end

            newNode = uitreenode(parentNode, 'Text', materialNodeName);
            newNode.Icon = fullfile(fileparts(mfilename('fullpath')), 'icon', 'expediente.png');

            expand(parentNode);
        end


        function updateKineticTable(app)

            model = app.ModelDropDown.Value;

            switch model
                case 'Newton'
                    app.KineticTable.Data = {
                        'k (1/s)', app.paramet_A.Value
                        };

                case 'Page'
                    app.KineticTable.Data = {
                        'k (1/s)', app.paramet_A.Value;
                        'n (-)',   app.paramet_R.Value
                        };

                case 'Arrhenius'
                    app.KineticTable.Data = {
                        'A (1/s)', app.paramet_A.Value;
                        'Ea (J/mol)', app.paramet_E.Value;
                        'Rg (J/molK)', 8.314
                        };
            end
        end


        function y = drawSection(app, ax, y, sectionTitle, sectionData, dy)

            text(ax, 0.05, y, app.safeText(sectionTitle), ...
                'FontSize', 13, 'FontWeight', 'bold', 'Interpreter', 'none');
            y = y - dy;

            n = size(sectionData, 1);
            for i = 1:n
                label = sectionData{i,1};
                value = sectionData{i,2};
                unit  = sectionData{i,3};

                line = sprintf('%s: %s %s', ...
                    app.safeText(label), ...
                    app.valueToString(value), ...
                    app.safeText(unit));

                text(ax, 0.07, y, line, 'FontSize', 10, 'Interpreter', 'none');
                y = y - dy;
            end

            y = y - 0.5*dy;
        end


        function lines = convertResultsTableToLines(app, T)

            lines = {};

            if isempty(T)
                lines = {'No results table available.'};
                return;
            end

            if istable(T)
                vars = T.Properties.VariableNames;

                if all(ismember({'Parameter','Value','Unit'}, vars))
                    for i = 1:height(T)
                        p = app.valueToString(T.Parameter{i});
                        v = app.valueToString(T.Value(i));
                        u = app.valueToString(T.Unit{i});
                        lines{end+1} = sprintf('%s: %s %s', p, v, u); %#ok<AGROW>
                    end
                else
                    lines{1} = 'Results table format not recognized.';
                end
                return;
            end

            if iscell(T)
                [nr, nc] = size(T);
                for i = 1:nr
                    rowTxt = '';
                    for j = 1:nc
                        cellTxt = app.valueToString(T{i,j});
                        if j == 1
                            rowTxt = cellTxt;
                        else
                            rowTxt = [rowTxt ' | ' cellTxt]; %#ok<AGROW>
                        end
                    end
                    lines{end+1} = rowTxt; %#ok<AGROW>
                end
                return;
            end

            lines = {'Results table format not supported.'};
        end


        function [cellData, headers] = getResultsTableForReport(app, T)

            headers = {'Parameter','Value','Unit'};

            if isempty(T)
                cellData = {'No results available', '', ''};
                return;
            end

            if istable(T)
                vars = T.Properties.VariableNames;

                if all(ismember({'Parameter','Value','Unit'}, vars))
                    n = height(T);
                    cellData = cell(n, 3);
                    for i = 1:n
                        cellData{i,1} = app.valueToString(T.Parameter{i});
                        cellData{i,2} = app.valueToString(T.Value(i));
                        cellData{i,3} = app.valueToString(T.Unit{i});
                    end
                else
                    cellData = {'Results table format not recognized', '', ''};
                end
                return;
            end

            if iscell(T)
                [nr, nc] = size(T);
                cellData = cell(max(nr,1), 3);

                for i = 1:nr
                    for j = 1:min(nc,3)
                        cellData{i,j} = app.valueToString(T{i,j});
                    end
                    for j = nc+1:3
                        cellData{i,j} = '';
                    end
                end

                if isempty(cellData)
                    cellData = {'No results available', '', ''};
                end
                return;
            end

            cellData = {'Results table format not supported', '', ''};
        end


        function tryExportAxes(app, axHandle, fileName)

            try
                if ~isempty(axHandle) && isvalid(axHandle)
                    exportgraphics(axHandle, fileName, ...
                        'BackgroundColor', 'white', ...
                        'Resolution', 300);
                end
            catch
                % Do not stop report generation if a plot cannot be exported`
            end
        end


        function stampLetterCanvas(app, f)
            set(f, 'Color', 'w');
            set(f, 'Units', 'inches');
            set(f, 'Position', [0.5 0.5 8.5 11]);
            set(f, 'PaperUnits', 'inches');
            set(f, 'PaperSize', [8.5 11]);
            set(f, 'PaperPosition', [0 0 8.5 11]);
        end


        function drawSimpleTable(app, parentFig, titleText, tableData, posTitle, posTable)

            if ~isempty(strtrim(app.safeText(titleText)))
                annotation(parentFig, 'textbox', posTitle, ...
                    'String', app.safeText(titleText), ...
                    'FontName', 'Times New Roman', ...
                    'FontSize', 12, ...
                    'FontWeight', 'bold', ...
                    'LineStyle', 'none', ...
                    'Interpreter', 'none');
            end

            if isempty(tableData)
                tableData = {'No data', '', ''};
            end

            cellData = tableData;
            for i = 1:size(cellData,1)
                for j = 1:size(cellData,2)
                    cellData{i,j} = app.valueToString(cellData{i,j});
                end
            end

            lines = {};
            lines{end+1} = sprintf('%-32s %-18s %-14s', 'Parameter', 'Value', 'Unit');
            lines{end+1} = repmat('-', 1, 70);

            for i = 1:size(cellData,1)
                c1 = app.truncateText(cellData{i,1}, 32);
                c2 = app.truncateText(cellData{i,2}, 18);
                c3 = app.truncateText(cellData{i,3}, 14);

                lines{end+1} = sprintf('%-32s %-18s %-14s', c1, c2, c3);
            end

            tableString = strjoin(lines, newline);

            annotation(parentFig, 'textbox', posTable, ...
                'String', tableString, ...
                'Interpreter', 'none', ...
                'FontName', 'Times New Roman', ...
                'FontSize', 10, ...
                'LineStyle', '-', ...
                'EdgeColor', [0 0 0], ...
                'BackgroundColor', [1 1 1], ...
                'HorizontalAlignment', 'left', ...
                'VerticalAlignment', 'top', ...
                'FitBoxToText', 'off');
        end


        function writeReportPage1(app, f, S)

            app.stampLetterCanvas(f);

            annotation(f, 'textbox', [0.06 0.955 0.88 0.03], ...
                'String', S.ReportTitle, ...
                'FontName', 'Times New Roman', ...
                'FontSize', 16, ...
                'FontWeight', 'bold', ...
                'LineStyle', 'none', ...
                'Interpreter', 'none');

            annotation(f, 'textbox', [0.06 0.925 0.88 0.02], ...
                'String', ['Date: ' S.DateString], ...
                'FontName', 'Times New Roman', ...
                'FontSize', 11, ...
                'LineStyle', 'none', ...
                'Interpreter', 'none');

            app.drawSimpleTable(f, '1. Input data', S.InputData, ...
                [0.06 0.865 0.88 0.025], [0.06 0.70 0.88 0.14]);

            app.drawSimpleTable(f, '2. Operating conditions', S.OperatingData, ...
                [0.06 0.64 0.88 0.025], [0.06 0.49 0.88 0.13]);

            app.drawSimpleTable(f, '3. Ambient conditions', S.AmbientData, ...
                [0.06 0.43 0.88 0.025], [0.06 0.32 0.88 0.09]);

            app.drawSimpleTable(f, '4. Solid properties', S.SolidProps, ...
                [0.06 0.26 0.88 0.025], [0.06 0.07 0.88 0.17]);
        end


        function writeReportPage2(app, f, S)

            app.stampLetterCanvas(f);

            annotation(f, 'textbox', [0.06 0.955 0.88 0.03], ...
                'String', S.ReportTitle, ...
                'FontName', 'Times New Roman', ...
                'FontSize', 16, ...
                'FontWeight', 'bold', ...
                'LineStyle', 'none', ...
                'Interpreter', 'none');

            annotation(f, 'textbox', [0.06 0.925 0.88 0.02], ...
                'String', ['Date: ' S.DateString], ...
                'FontName', 'Times New Roman', ...
                'FontSize', 11, ...
                'LineStyle', 'none', ...
                'Interpreter', 'none');

            app.drawSimpleTable(f, '5. Air properties', S.AirProps, ...
                [0.06 0.865 0.88 0.025], [0.06 0.72 0.88 0.12]);

            app.drawSimpleTable(f, '6. Drying gas properties', S.GasProps, ...
                [0.06 0.66 0.88 0.025], [0.06 0.53 0.88 0.11]);

            app.drawSimpleTable(f, '7. Kinetic parameters', S.KineticData, ...
                [0.06 0.47 0.88 0.025], [0.06 0.34 0.88 0.11]);

            app.drawSimpleTable(f, '8. Model and result panel values', S.ResultPanel, ...
                [0.06 0.28 0.88 0.025], [0.06 0.15 0.88 0.11]);
        end


        function writeResultsTablePage(app, f, S)

            app.stampLetterCanvas(f);

            annotation(f, 'textbox', [0.06 0.955 0.88 0.03], ...
                'String', S.ReportTitle, ...
                'FontName', 'Times New Roman', ...
                'FontSize', 16, ...
                'FontWeight', 'bold', ...
                'LineStyle', 'none', ...
                'Interpreter', 'none');

            annotation(f, 'textbox', [0.06 0.925 0.88 0.02], ...
                'String', ['Date: ' S.DateString], ...
                'FontName', 'Times New Roman', ...
                'FontSize', 11, ...
                'LineStyle', 'none', ...
                'Interpreter', 'none');

            annotation(f, 'textbox', [0.06 0.875 0.88 0.025], ...
                'String', '9. Summary results table', ...
                'FontName', 'Times New Roman', ...
                'FontSize', 12, ...
                'FontWeight', 'bold', ...
                'LineStyle', 'none', ...
                'Interpreter', 'none');

            [cellData, ~] = app.getResultsTableForReport(S.ResultsTable);

            app.drawSimpleTable(f, '', cellData, ...
                [0 0 0 0], [0.06 0.08 0.88 0.77]);
        end


        function writeImagePage(app, f, pageTitle, imageFile)

            app.stampLetterCanvas(f);

            annotation(f, 'textbox', [0.06 0.94 0.88 0.035], ...
                'String', app.safeText(pageTitle), ...
                'FontName', 'Times New Roman', ...
                'FontSize', 16, ...
                'FontWeight', 'bold', ...
                'LineStyle', 'none', ...
                'Interpreter', 'none');

            ax = axes('Parent', f, 'Units', 'normalized', 'Position', [0.08 0.08 0.84 0.82]);
            axis(ax, 'off');

            if isfile(imageFile)
                img = imread(imageFile);
                imshow(img, 'Parent', ax);
            else
                text(ax, 0.1, 0.5, 'Image file not available.', ...
                    'FontName', 'Times New Roman', ...
                    'FontSize', 11, ...
                    'Interpreter', 'none');
            end
        end


        function generateFullPDFReport(app)

            if isempty(app.UITableResults.Data)
                uialert(app.UIFigure, ...
                    'Run the calculation first so the results table is available.', ...
                    'Report');
                return;
            end

           
defaultName = ['RotaryDryerReport_' datestr(now,'yyyy_mm_dd_HHMMSS') '.pdf'];

appPath = fileparts(which(class(app)));
projectRoot = fileparts(appPath);
defaultReportFolder = fullfile(projectRoot, 'outputs', 'reports');

if ~exist(defaultReportFolder, 'dir')
    mkdir(defaultReportFolder);
end

defaultFullName = fullfile(defaultReportFolder, defaultName);

[file, path] = uiputfile(defaultFullName, 'Save report as');

if isequal(file,0) || isequal(path,0)
    return;
end


            if isequal(file,0) || isequal(path,0)
                return;
            end

            pdfFile = fullfile(path, file);

            if isfile(pdfFile)
                delete(pdfFile);
            end

            tempFolder = fullfile(tempdir, ['RotaryDryerReport_' char(java.util.UUID.randomUUID)]);
            mkdir(tempFolder);

            try
                S = app.collectReportData();

                mainPlotFile = fullfile(tempFolder, 'MainPlot.png');
                kinPlotFile  = fullfile(tempFolder, 'KineticPlot.png');

                app.tryExportAxes(app.UIAxes, mainPlotFile);
                app.tryExportAxes(app.ArrheniusAxes, kinPlotFile);

                f1 = figure('Visible','off', 'Color','w', ...
                    'Units','inches', 'Position',[0.5 0.5 8.5 11], ...
                    'PaperUnits','inches', 'PaperSize',[8.5 11], ...
                    'PaperPosition',[0 0 8.5 11]);
                app.writeReportPage1(f1, S);
                drawnow;
                exportgraphics(f1, pdfFile, 'Append', false, 'ContentType','vector');
                close(f1);

                f2 = figure('Visible','off', 'Color','w', ...
                    'Units','inches', 'Position',[0.5 0.5 8.5 11], ...
                    'PaperUnits','inches', 'PaperSize',[8.5 11], ...
                    'PaperPosition',[0 0 8.5 11]);
                app.writeReportPage2(f2, S);
                drawnow;
                exportgraphics(f2, pdfFile, 'Append', true, 'ContentType','vector');
                close(f2);

                f3 = figure('Visible','off', 'Color','w', ...
                    'Units','inches', 'Position',[0.5 0.5 8.5 11], ...
                    'PaperUnits','inches', 'PaperSize',[8.5 11], ...
                    'PaperPosition',[0 0 8.5 11]);
                app.writeResultsTablePage(f3, S);
                drawnow;
                exportgraphics(f3, pdfFile, 'Append', true, 'ContentType','vector');
                close(f3);

                if isfile(mainPlotFile)
                    f4 = figure('Visible','off', 'Color','w', ...
                        'Units','inches', 'Position',[0.5 0.5 8.5 11], ...
                        'PaperUnits','inches', 'PaperSize',[8.5 11], ...
                        'PaperPosition',[0 0 8.5 11]);
                    app.writeImagePage(f4, 'Main simulation plot', mainPlotFile);
                    drawnow;
                    exportgraphics(f4, pdfFile, 'Append', true, 'ContentType','image');
                    close(f4);
                end

                if isfile(kinPlotFile)
                    f5 = figure('Visible','off', 'Color','w', ...
                        'Units','inches', 'Position',[0.5 0.5 8.5 11], ...
                        'PaperUnits','inches', 'PaperSize',[8.5 11], ...
                        'PaperPosition',[0 0 8.5 11]);
                    app.writeImagePage(f5, 'Kinetic fitting plot', kinPlotFile);
                    drawnow;
                    exportgraphics(f5, pdfFile, 'Append', true, 'ContentType','image');
                    close(f5);
                end

                if isfile(mainPlotFile), delete(mainPlotFile); end
                if isfile(kinPlotFile), delete(kinPlotFile); end

                if isfolder(tempFolder)
                    files = dir(fullfile(tempFolder,'*'));
                    for k = 1:numel(files)
                        if ~files(k).isdir
                            delete(fullfile(tempFolder, files(k).name));
                        end
                    end
                    rmdir(tempFolder);
                end

                uialert(app.UIFigure, ['PDF report created successfully:' newline pdfFile], 'Report');

            catch ME

                if isfolder(tempFolder)
                    try
                        files = dir(fullfile(tempFolder,'*'));
                        for k = 1:numel(files)
                            if ~files(k).isdir
                                delete(fullfile(tempFolder, files(k).name));
                            end
                        end
                        rmdir(tempFolder);
                    catch
                    end
                end

                uialert(app.UIFigure, ['Report generation failed:' newline ME.message], 'Report Error');
            end
        end

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            appPath = fileparts(which(class(app)));
            projectRoot = fileparts(appPath);
            corePath = fullfile(projectRoot, 'core');

            if exist(corePath, 'dir')
                addpath(genpath(corePath));
                rehash;
            else
                uialert(app.UIFigure, ...
                    'The core folder was not found.', ...
                    'Startup error');
                return;
            end

            updateStatusLamps(app, [], [], []);


          % --- Kinetics fit table ---
app.KineticTable.ColumnName = {'Model','T (°C)','k','k1','k2','a','b','c','n','Xe','RMSE','R2'};
app.KineticTable.ColumnEditable = false(1,12);
app.KineticTable.ColumnFormat = {'char','numeric','numeric','numeric','numeric','numeric','numeric','numeric','numeric','numeric','numeric','numeric'};
app.KineticTable.ColumnWidth = {70,70,70,70,70,70,70,70,70,70,70,70};

app.KineticTable.Data = {};
app.KineticTable.RowName = {};

        end

        % Button pushed function: SolveButton
        function SolveButtonPushed(app, event)


in = struct();

% =========================================================
% Input data from GUI
% Solid moisture is entered on a dry basis (d.b.)
% =========================================================

in.Ms = app.Free.Value * 1000/3600;   % total wet feed, kg/s

% Moisture entered in GUI as % dry basis
in.X_in_db     = app.InitialMouisture.Value/100;
in.X_target_db = app.FinalMouisturetarget.Value/100;

in.Tgin_C   = app.GasInletTemperature.Value;
in.Ts_in_C  = app.SolidTemperature.Value;
in.Ts_out_C = app.MaxSolidTemperature.Value;

in.lossFrac = app.fractionalheatloss.Value;
in.f_fill   = app.FillingDegree.Value;
in.Vp       = app.PeripheralVelocity.Value;
in.phiD0    = app.phiRet.Value;

in.Tamb_C = app.AmbientTemperature.Value;
in.h_alt  = app.Altitude.Value;

in.den_s = app.Bulksdensity.Value;
in.Ks    = app.Ksolid.Value;
in.Cps   = app.Cpsolid.Value;
in.es    = app.Es.Value;

in.h_step = app.Stip.Value;
in.Lmax   = app.Lm_ax.Value;
in.H_tol  = app.Tolerance.Value;

% Kinetic parameters
in.A   = app.paramet_A.Value;
in.E   = app.paramet_E.Value;
in.Heq = app.paramet_Heq.Value;   % kg water/kg dry solid

% Gas properties
in.Yin   = app.w_aire.Value;
in.Cpg   = app.Cpgas.Value;
in.K_gas = app.Kgas.Value;
in.rho_g = app.Density_gas.Value;
in.mu_g  = app.viscosidad_D_gas.Value;
in.eg    = app.Egas.Value;

% Dryer design values
in.Di   = app.Diametro_test.Value;
in.Mgas = app.RateMgs.Value;      % kg/s


% =========================================================
% Intermediate mass and energy balance quantities
% X = kg water / kg dry solid
% =========================================================

in.Ms_h = in.Ms * 3600;   % total wet feed, kg/h

X_in_db  = in.X_in_db;
X_out_db = in.X_target_db;

% Dry-solid mass flow

in.Ss_h = in.Ms_h / (1 + X_in_db);   % kg dry solid/h

% Water mass flows
water_in_h  = in.Ss_h * X_in_db;
water_out_h = in.Ss_h * X_out_db;

in.m_evap_h = water_in_h - water_out_h;   % kg water/h


% =========================================================
% Solid enthalpy balance
% =========================================================

cp_s     = in.Cps / 1000;                % kJ/kg/K
cp_w_in  = Cp_h2o(in.Ts_in_C) / 1000;   % kJ/kg/K
cp_w_out = Cp_h2o(in.Ts_out_C) / 1000;  % kJ/kg/K

Hs_in = cp_s * in.Ts_in_C + ...
        X_in_db * cp_w_in * in.Ts_in_C;

Hs_out = cp_s * in.Ts_out_C + ...
         X_out_db * cp_w_out * in.Ts_out_C;

in.rhs_h = in.Ss_h * (Hs_out - Hs_in);   % kJ/h


% =========================================================
% Axial solver
% =========================================================

try
    out = solveAxialDryerModel(in);

catch ME
    uialert(app.UIFigure, ME.message, 'Solver error');
    updateStatusLamps(app, false, false, true);
    return;
end


% =========================================================
% Extract solver results
% =========================================================

x_ax  = out.x_ax;
Tg_ax = out.Tg_ax;
Ts_ax = out.Ts_ax;
Tw_ax = out.Tw_ax;

% Solid moisture profile, dry basis
X_ax = out.X_ax;

% Gas humidity ratio
Y_ax = out.Y_ax;

app.SolverResults = out;

L_req          = out.L_req;
Tg_out_K       = out.Tg_out_K;
Ts_out_K_model = out.Ts_out_K_model;
RPM            = out.RPM;
Ss_design      = out.Ss_design;
Trx            = out.Trx;

solverOK        = out.solverOK;
targetOK        = out.targetOK;
validityWarning = out.validityWarning;

Ms_h     = in.Ms_h;
Ss_h     = in.Ss_h;
m_evap_h = in.m_evap_h;
rhs_h    = in.rhs_h;
lossFrac = in.lossFrac;
Mgas     = in.Mgas;
Di       = in.Di;
Yout     = Y_ax(end);


% =========================================================
% Plot temperature profiles in UIAxes
% =========================================================

cla(app.UIAxes);

plot(app.UIAxes, ...
    x_ax, Tg_ax - 273.15, ...
    x_ax, Ts_ax - 273.15, ...
    'LineWidth', 1.5);

grid(app.UIAxes, 'on');
xlabel(app.UIAxes, 'x (m)');
ylabel(app.UIAxes, 'Temperature (°C)');
title(app.UIAxes, 'Gas and solid temperature profiles');

legend(app.UIAxes, ...
    {'Gas','Solid'}, ...
    'Location', 'best');


% =========================================================
% Fuel data
% =========================================================

fuelType = app.FuelTypeDropDown.Value;

switch lower(strtrim(fuelType))

    case 'natural gas'
        LHV_kJkg = 50000;
        eta_burner = 0.85;

    case 'lpg'
        LHV_kJkg = 46000;
        eta_burner = 0.85;

    case 'diesel'
        LHV_kJkg = 42800;
        eta_burner = 0.82;

    case 'fuel oil'
        LHV_kJkg = 40500;
        eta_burner = 0.80;

    case 'biomass pellets'
        LHV_kJkg = 17000;
        eta_burner = 0.75;

    case 'coal'
        LHV_kJkg = 25000;
        eta_burner = 0.78;

    otherwise
        uialert(app.UIFigure, ...
            'Unsupported fuel type.', ...
            'Fuel error');
        return;
end


% =========================================================
% Energy balance and fuel consumption
% =========================================================

Hv_kJkg = 2502.3;   % latent heat, kJ/kg water

Q_sensible_h = rhs_h;
Q_evap_h     = Hv_kJkg * m_evap_h;
Q_process_h  = Q_sensible_h + Q_evap_h;

Q_corrected_h = ...
    Q_process_h / max(1 - lossFrac, eps);

m_fuel_h = ...
    Q_corrected_h / max(eta_burner * LHV_kJkg, eps);

fuel_per_wetSolid = ...
    m_fuel_h / max(Ms_h, eps);

fuel_per_drySolid = ...
    m_fuel_h / max(Ss_h, eps);

Q_util_h = Q_process_h;
Q_sup_h  = m_fuel_h * LHV_kJkg * eta_burner;

eta_th = ...
    100 * Q_util_h / max(Q_sup_h, eps);

Mgas_h = Mgas * 3600;


% =========================================================
% Results table
% =========================================================

Ptab = {
    'Dry-solid flow rate',               Ss_h,                    'kg/h';
    'Evaporated water flow rate',        m_evap_h,                'kg/h';
    'Sensible heat demand',              Q_sensible_h,            'kJ/h';
    'Evaporation heat demand',           Q_evap_h,                'kJ/h';
    'Total process heat demand',         Q_process_h,             'kJ/h';
    'Corrected heat demand with losses', Q_corrected_h,           'kJ/h';
    'Drying-gas flow rate',              Mgas_h,                  'kg/h';
    'Fuel consumption',                  m_fuel_h,                'kg/h';

    'Wet feed flow rate',                Ms_h,                    'kg/h';
    'Dry air flow',                      Mgas,                    'kg/s';
    'Diameter',                          Di,                      'm';
    'Rotation speed',                    RPM,                     'rpm';
    'Required length',                   L_req,                   'm';
    'Residence time',                    Trx,                     'min';
    'Inclination',                       Ss_design,               '%';
    'Outlet gas temperature',            Tg_out_K - 273.15,       '°C';
    'Outlet solid temperature',          Ts_out_K_model - 273.15, '°C';
    'Outlet humidity ratio',             Yout,                    'kg/kg dry air';
    'Final solid moisture',              X_ax(end),               'kg water/kg dry solid';

    'Fuel type',                         fuelType,                '-';
    'Fuel LHV',                          LHV_kJkg,                'kJ/kg';
    'Fuel per wet solid',                fuel_per_wetSolid,       'kg/kg wet solid';
    'Fuel per dry solid',                fuel_per_drySolid,       'kg/kg dry solid';
    'Thermal efficiency',                eta_th,                  '%'
};

app.UITableResults.Data = Ptab;
app.UITableResults.ColumnName = {'Parameter','Value','Unit'};
app.UITableResults.RowName = {};


% =========================================================
% Status lamps
% =========================================================

updateStatusLamps(app, ...
    solverOK, ...
    targetOK, ...
    validityWarning);


% =========================================================
% TEMPORARY: Export GUI tabs as PDF and PNG without resizing the app
% Remove this block after capturing the figures
% =========================================================

try
    % Select output folder manually
    outDir = uigetdir(pwd, 'Select folder to save GUI tab files');

    if isequal(outDir, 0)
        disp('GUI export cancelled by user.');
        return;
    end

    % Do NOT change app.UIFigure.Position
    drawnow;
    pause(0.5);

    % Find tab groups in the app
    tabGroups = findall(app.UIFigure, 'Type', 'uitabgroup');

    if isempty(tabGroups)

        pdfPath = fullfile(outDir, 'RotaryDryerDesigner_visible_window.pdf');
        pngPath = fullfile(outDir, 'RotaryDryerDesigner_visible_window.png');

        exportapp(app.UIFigure, pdfPath);
        exportapp(app.UIFigure, pngPath);

        fprintf('Saved PDF: %s\n', pdfPath);
        fprintf('Saved PNG: %s\n', pngPath);

    else
        % Use the tab group with the largest number of tabs
        nTabs = arrayfun(@(tg) numel(tg.Children), tabGroups);
        [~, idxMain] = max(nTabs);
        mainTabGroup = tabGroups(idxMain);

        % Save current selected tab
        originalTab = mainTabGroup.SelectedTab;

        % Get tabs in visual order
        tabs = flipud(mainTabGroup.Children);

        for it = 1:numel(tabs)

            mainTabGroup.SelectedTab = tabs(it);
            drawnow;
            pause(0.8);

            % Clean tab title for file name
            tabTitle = string(tabs(it).Title);
            tabTitle = regexprep(tabTitle, '[^\w\s-]', '');
            tabTitle = strtrim(tabTitle);
            tabTitle = strrep(tabTitle, ' ', '_');

            if strlength(tabTitle) == 0
                tabTitle = "Tab_" + string(it);
            end

            baseName = sprintf('RotaryDryerDesigner_%02d_%s', it, tabTitle);

            pdfPath = fullfile(outDir, baseName + ".pdf");
            pngPath = fullfile(outDir, baseName + ".png");

            % Export current visible tab as PDF and PNG
            exportapp(app.UIFigure, pdfPath);
            exportapp(app.UIFigure, pngPath);

            fprintf('Saved PDF: %s\n', pdfPath);
            fprintf('Saved PNG: %s\n', pngPath);
        end

        % Restore original tab
        mainTabGroup.SelectedTab = originalTab;
        drawnow;
    end

    % -----------------------------------------------------
    % Save editing instructions
    % -----------------------------------------------------
    notePath = fullfile(outDir, 'README_editing_workflow.txt');

    fid = fopen(notePath, 'w');
    if fid ~= -1
        fprintf(fid, 'Recommended editing workflow:\n');
        fprintf(fid, '1. Open the exported PDF in Inkscape.\n');
        fprintf(fid, '2. Save the file as SVG to keep an editable master file.\n');
        fprintf(fid, '3. Add rectangles, arrows, labels, and annotations in the SVG file.\n');
        fprintf(fid, '4. Export the final annotated figure as PDF for LaTeX or journal submission.\n');
        fprintf(fid, '5. Keep the SVG file as the editable source file.\n');
        fclose(fid);
    end

    uialert(app.UIFigure, ...
        ['GUI tab files saved in: ' outDir], ...
        'Export completed');

catch ME_capture
    warning('GUI export failed: %s', ME_capture.message);

    uialert(app.UIFigure, ...
        ['GUI export failed: ' ME_capture.message], ...
        'Export error');
end


        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)

          answer = inputdlg('Enter the material name:', ...
    'Save material', [1 50]);

if isempty(answer)
    return
end

materialName = strtrim(answer{1});
if isempty(materialName)
    uialert(app.UIFigure, 'Enter a valid material name.', 'Name required');
    return
end

% 2) Safe file name
fileName = regexprep(materialName, '[^\w\- ]', '');
fileName = strtrim(fileName);
fileName = strrep(fileName, ' ', '_');

if isempty(fileName)
    uialert(app.UIFigure, 'The name contains invalid characters.', 'Name error');
    return
end

% 3) Materials path
appPath = fileparts(mfilename('fullpath'));
projectRoot = fileparts(appPath);
libPath = fullfile(projectRoot, 'materials');

if ~exist(libPath, 'dir')
    mkdir(libPath);
end
filePath = fullfile(libPath, [fileName '.txt']);

% 4) Build JSON structure
data = struct();

data.MaterialName = fileName;

data.PhysicalProperties = struct( ...
    'ThermalConductivity_W_mK', app.Ksolid.Value, ...
    'SpecificHeat_J_kgK',       app.Cpsolid.Value, ...
    'TrueDensity_kg_m3',        app.TrueDensity.Value, ...
    'BulkDensity_kg_m3',        app.Bulksdensity.Value, ...
    'Emissivity',               app.Es.Value, ...
    'ParticleDiameter_m',       app.ParticleDiameter.Value);

data.KineticProperties = struct( ...
    'Model',                    'Newton', ...
    'A_preExponential_1_s',     app.paramet_A.Value, ...
    'ActivationEnergy_J_mol',   app.paramet_E.Value, ...
    'EquilibriumMoisture_kg_kg',app.paramet_Heq.Value, ...
    'ReactionOrder',            app.paramet_R.Value );

data.MechanicalProperties = struct( ...
    'AngleOfRepose_deg',        NaN, ...
    'FrictionFactor',           NaN, ...
    'CohesivenessIndex',        NaN );

data.Metadata = struct( ...
    'Reference',                'User-defined material', ...
    'Notes',                    'Created from App Designer interface' );

% 5) Save JSON
fid = -1;
try
    try
        jsonText = jsonencode(data, 'PrettyPrint', true);
    catch
        jsonText = jsonencode(data);
    end

    fid = fopen(filePath, 'w');
    if fid == -1
        uialert(app.UIFigure, 'The material file could not be created.', 'File error');
        return
    end

    fwrite(fid, jsonText, 'char');
    fclose(fid);

catch ME
    if fid ~= -1
        fclose(fid);
    end
    uialert(app.UIFigure, ['Error while saving: ' ME.message], 'Error');
    return
end

app.addMaterialToTree(fileName);
uialert(app.UIFigure, ['Material saved: ' fileName '.txt'], 'Success');


        end

        % Size changed function: PropertiesTab
        function PropertiesTabSizeChanged(app, event)

            position = app.PropertiesTab.Position;

            app.loadMaterialsFromLIB();
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)

if isempty(fieldnames(app.SolverResults))
    return;
end

x_ax  = app.SolverResults.x_ax;
Tg_ax = app.SolverResults.Tg_ax;
Ts_ax = app.SolverResults.Ts_ax;
X_ax  = app.SolverResults.X_ax;

cla(app.UIAxes,'reset');
hold(app.UIAxes,'off');
grid(app.UIAxes,'on');
xlabel(app.UIAxes,'x (m)');

if app.TempButton.Value

    yyaxis(app.UIAxes,'left');

    plot(app.UIAxes, ...
        x_ax, Tg_ax - 273.15, ...
        x_ax, Ts_ax - 273.15, ...
        'LineWidth',1.5);

    ylabel(app.UIAxes,'Temperature (°C)');
    title(app.UIAxes,'Gas and solid temperature');
    legend(app.UIAxes, {'Gas','Solid'}, 'Location','best');

elseif app.HumidityButton.Value

    yyaxis(app.UIAxes,'left');

    plot(app.UIAxes, ...
        x_ax, X_ax, ...
        'LineWidth',1.5);

    ylabel(app.UIAxes,'X (kg/kg d.b.)');
    title(app.UIAxes,'Solid moisture profile');
    legend(app.UIAxes, {'X'}, 'Location','best');

elseif app.T_x_M.Value

    yyaxis(app.UIAxes,'left');

    plot(app.UIAxes, ...
        x_ax, Tg_ax - 273.15, ...
        'LineWidth',1.5);

    hold(app.UIAxes,'on');

    plot(app.UIAxes, ...
        x_ax, Ts_ax - 273.15, ...
        'LineWidth',1.5);

    ylabel(app.UIAxes,'Temperature (°C)');

    yyaxis(app.UIAxes,'right');

    plot(app.UIAxes, ...
        x_ax, X_ax, '--', ...
        'LineWidth',1.5);

    ylabel(app.UIAxes,'X (kg/kg d.b.)');

    hold(app.UIAxes,'off');

    title(app.UIAxes,'Temperature and moisture profiles');

    legend(app.UIAxes, ...
        {'Gas','Solid','X'}, ...
        'Location','best');

end

        end

        % Selection changed function: MaterialTree
        function MaterialTreeSelectionChanged(app, event)
            % Recargar materiales guardados
            app.loadMaterialsFromLIB();

            node = event.SelectedNodes;

            if isempty(node)
                return
            end


            if numel(node) > 1
                node = node(1);
            end


            if ~isempty(node.Children)
                return
            end

            materialName = node.Text;

            app.loadMaterial(materialName);


        end

        % Value changed function: ModelDropDown
        function ModelDropDownValueChanged(app, event)

         model = app.ModelDropDown.Value;

switch model

    case 'Newton'
        app.KineticEquationLabel.Text = ...
            '$MR=\exp(-k\,t)$';

    case 'Page'
        app.KineticEquationLabel.Text = ...
            '$MR=\exp(-k\,t^n)$';

    case 'Modified Page'
        app.KineticEquationLabel.Text = ...
            '$MR=\exp\left[-(k\,t)^n\right]$';

    case 'Henderson-Pabis'
        app.KineticEquationLabel.Text = ...
            '$MR=a\,\exp(-k\,t)$';

    case 'Logarithmic'
        app.KineticEquationLabel.Text = ...
            '$MR=a\,\exp(-k\,t)+c$';

    case 'Two-Term'
        app.KineticEquationLabel.Text = ...
            '$MR=a\,\exp(-k_1\,t)+b\,\exp(-k_2\,t)$';

    case 'Wang-Singh'
        app.KineticEquationLabel.Text = ...
            '$MR=1+a\,t+b\,t^2$';

    case 'Midilli-Kucuk'
        app.KineticEquationLabel.Text = ...
            '$MR=a\,\exp(-k\,t^n)+b\,t$';

    otherwise
        app.KineticEquationLabel.Text = '';

end

app.KineticEquationLabel.Interpreter = 'latex';


        end

        % Button pushed function: DeleteButton
        function DeleteButtonPushed(app, event)


% 1) Selected node
node = app.MaterialTree.SelectedNodes;

if isempty(node)
    uialert(app.UIFigure, 'Select a material to delete.', 'Delete material');
    return
end

% If multiple nodes are selected, take the first one
if numel(node) > 1
    node = node(1);
end

% 2) Do not allow deleting folders (nodes with children)
if ~isempty(node.Children)
    uialert(app.UIFigure, 'Select a material, not a folder.', 'Delete material');
    return
end

materialName = node.Text;

% Protect base materials
baseMaterials = {'Lateritic_ore'};

if any(strcmp(materialName, baseMaterials))
    uialert(app.UIFigure, ...
        'This base material cannot be deleted.', ...
        'Delete material');
    return
end

choice = uiconfirm(app.UIFigure, ...
    ['Delete material "' materialName '"?'], ...
    'Confirm deletion', ...
    'Options', {'Yes','No'}, ...
    'DefaultOption', 2, ...
    'CancelOption', 2);

if ~strcmp(choice, 'Yes')
    return
end

appPath = fileparts(mfilename('fullpath'));
projectRoot = fileparts(appPath);
filePath = fullfile(projectRoot, 'materials', [materialName '.txt']);

if isfile(filePath)
    try
        delete(filePath);
    catch ME
        uialert(app.UIFigure, ['The file could not be deleted: ' ME.message], 'Error');
        return
    end
end

delete(node);

uialert(app.UIFigure, ['Material deleted: ' materialName], 'Success');

        end

        % Button pushed function: AddrowButton
        function AddrowButtonPushed(app, event)
            model = app.ModelDropDown.Value;
            T_C   = app.GasInletTemperature.Value;  % o el campo que definas

            % Leer data actual
            D = app.KineticTable.Data;


            if ~isempty(D)
                existingModel = string(D{1,1});
                if existingModel ~= string(model)

                    D = {};
                end
            end


            newRow = {model, T_C, NaN, NaN, NaN, NaN};

            if isempty(D)
                D = newRow;
            else
                D = [D; newRow];
            end

            app.KineticTable.Data = D;
        end

        % Button pushed function: FitArrheniusButton
        function FitArrheniusButtonPushed(app, event)

D = app.KineticTable.Data;

if isempty(D) || size(D,1) < 3
    uialert(app.UIFigure, ...
        'At least 3 rows are required to fit the Arrhenius model.', ...
        'Arrhenius');
    return
end

T_C = cell2mat(D(:,2));   % T (°C)
k   = cell2mat(D(:,3));   % k

ok = isfinite(T_C) & isfinite(k) & (k > 0);
T_C = T_C(ok);
k   = k(ok);

if numel(k) < 3
    uialert(app.UIFigure, ...
        'There are not enough valid points (k > 0) for ln(k).', ...
        'Arrhenius');
    return
end

T_K = T_C + 273.15;
x   = 1./T_K;         % 1/K
y   = log(k);         % ln(k)

p = polyfit(x, y, 1);
m = p(1);
b = p(2);

yhat = polyval(p, x);
SSres = sum((y - yhat).^2);
SStot = sum((y - mean(y)).^2);
R2 = 1 - SSres/max(SStot, eps);

% Plot
cla(app.ArrheniusAxes);

h1 = plot(app.ArrheniusAxes, x, y, 'o', ...
    'DisplayName', 'Estimated kinetic constants');

hold(app.ArrheniusAxes, 'on');

xx = linspace(min(x), max(x), 100);

h2 = plot(app.ArrheniusAxes, xx, polyval(p, xx), '-', ...
    'LineWidth', 1.2, ...
    'DisplayName', 'Arrhenius fit');

grid(app.ArrheniusAxes, 'on');

xlabel(app.ArrheniusAxes, '1/T (1/K)');
ylabel(app.ArrheniusAxes, 'ln(k)');
title(app.ArrheniusAxes, 'Arrhenius linear fit: ln(k) vs 1/T');

legend(app.ArrheniusAxes, [h1 h2], ...
    {'Estimated kinetic constants', 'Arrhenius fit'}, ...
    'Location', 'best');

hold(app.ArrheniusAxes, 'off');

eq1 = sprintf('ln(k) = %.4g*(1/T) + %.4g', m, b);
subtitle(app.ArrheniusAxes, eq1);

R = 8.314;
Ea = -m*R/1000;
Aa = exp(b)/10;

msg = sprintf('A = %.4g 1/s | Ea = %.4g kJ/mol | R^2 = %.4f', Aa, Ea, R2);
uialert(app.UIFigure, msg, 'Arrhenius fit');

app.paramet_A.Value = Aa;
app.paramet_E.Value = Ea;
app.paramet_R.Value = R;
        end

        % Button pushed function: airProps_cal
        function airProps_calButtonPushed(app, event)


            % Llamada a la función
            Tdb_C    = app.AmbientTemperature.Value;          % °C
            RH_frac  = app.AmbientRelativeHumidity.Value/100; % 0–1
            h_alt    = app.Altitude.Value;                    % m

            [Kaire, den_Aire, Cp_aire, Vd_Aire, Vs_Aire, Alfa_aire, Pr_Aire, BET_A] = airProps(Tdb_C);

            P_Pa      = 101325 * (1 - 2.25577e-5 * h_alt)^5.2559;  % Pa
            Patm_kPa  = P_Pa / 1000;                                  % kPa

            props = humidAirProps_T_RH(Tdb_C, RH_frac, Patm_kPa);


            app.w_aire.Value = props.w;      % kg_v/kg aire seco
            app.h_aire.Value = props.h;      % kg_v/kg aire seco

            app.Cp_aire_box.Value = Cp_aire;
            app.K_aire.Value = Kaire;
            app.den_aire.Value = den_Aire;


        end

        % Callback function
        function Gas_Props_calButtonPushed(app, event)

            Tgin_C = app.GasInletTemperature.Value;     % degC
            Tgin_o = app.MaxSolidTemperature.Value;
            h_alt  = app.Altitude.Value;                % m

            P_Pa = 101325 * (1 - 2.25577e-5 * h_alt)^5.2559;
            Tg_avg_C = 0.5*(Tgin_C + Tgin_o);

            fuelTypeUI = strtrim(lower(app.FuelTypeDropDown.Value));

            switch fuelTypeUI
                case 'natural gas'
                    fuelModel = 'NaturalGas';
                case 'lpg'
                    fuelModel = 'LPG';
                case 'diesel'
                    fuelModel = 'Diesel';
                case 'fuel oil'
                    fuelModel = 'FuelOil';
                case 'biomass pellets'
                    fuelModel = 'Biomass';
                case 'coal'
                    fuelModel = 'Coal';
                otherwise
                    uialert(app.UIFigure, 'Unsupported fuel type for gas properties.', 'Fuel error');
                    return;
            end

            [ Cpg, K_gas, rho_g, mu_g, Pr_g, eps_g ] = ...
                gasPropsFromAirEquiv(Tg_avg_C, P_Pa, fuelModel);

            app.Tm_gas.Value = Tg_avg_C;
            app.Kgas.Value = K_gas;
            app.Cpgas.Value = Cpg;
            app.Density_gas.Value = rho_g;
            app.viscosidad_D_gas.Value = mu_g;
            app.Egas.Value = eps_g;

        end

        % Button pushed function: DesignButton
        function DesignButtonPushed(app, event)
            
            in = struct();

            in.Ms        = app.Free.Value * 1000/3600;
            in.X_in_db     = app.InitialMouisture.Value/100;
            in.X_target_db = app.FinalMouisturetarget.Value/100;
            in.Tgin_C    = app.GasInletTemperature.Value;
            in.Ts_in_C   = app.SolidTemperature.Value;
            in.Ts_out_C  = app.MaxSolidTemperature.Value;

            in.lossFrac  = app.fractionalheatloss.Value;
            in.Cps       = app.Cpsolid.Value;
            in.Yin       = app.w_aire.Value;

            in.Cpg       = app.Cpgas.Value;
            in.K_gas     = app.Kgas.Value;
            in.rho_g     = app.Density_gas.Value;
            in.mu_g      = app.viscosidad_D_gas.Value;

            in.v_g_des   = app.Vgas_teo.Value;

            try
                out = computeDesignGasFlow(in);
            catch ME
                uialert(app.UIFigure, ME.message, 'Design calculation error');
                return;
            end

            app.RateMgs.Value = out.m_dot_g;

            X1_db   = out.X1_db;
            X2_db   = out.X2_db;
            Ms_h    = out.Ms_h;
            Ss_h    = out.Ss_h;
            m_evap_h = out.m_evap_h;
            Hg_in   = out.Hg_in;
            Hs_out  = out.Hs_out;
            Yout    = out.Yout;
            rhs_h   = out.rhs_h;
            m_dot_g = out.m_dot_g;
            v_g_des = out.v_g_des;

            G_h = out.GS_h;
            G_s = out.m_dot_g;


        end

        % Button pushed function: Impor_data
        function Impor_dataButtonPushed(app, event)

appPath = fileparts(which(class(app)));
projectRoot = fileparts(appPath);
defaultKineticsFolder = fullfile(projectRoot, 'data', 'kinetics');

if ~exist(defaultKineticsFolder, 'dir')
    mkdir(defaultKineticsFolder);
end

defaultFullName = fullfile(defaultKineticsFolder, 'drying_data.xlsx');

[f,p] = uigetfile( ...
    {fullfile(defaultKineticsFolder, '*.txt;*.csv'), ...
        'Text/CSV (*.txt, *.csv)'; ...
     fullfile(defaultKineticsFolder, '*.xlsx;*.xls'), ...
        'Excel (*.xlsx, *.xls)'}, ...
    'Select data file', ...
    defaultFullName);

if isequal(f,0)
    return;
end

filePath = fullfile(p,f);
[~,~,ext] = fileparts(filePath);
ext = lower(ext);

try

    switch ext

        case {'.txt','.csv'}
            Tdata = parse_moisture_txt(filePath);

        case {'.xlsx','.xls'}
            Tdata = parse_moisture_excel(filePath);

        otherwise
            uialert(app.UIFigure, ...
                'Unsupported file type.', ...
                'Import error');
            return;
    end

catch ME

    uialert(app.UIFigure, ...
        sprintf('Import failed:\n%s', ME.message), ...
        'Import error');

    return;
end


% =========================================================
% Store imported kinetic data
% =========================================================
app.ImportedKineticData = Tdata;


% =========================================================
% Display imported data in table
% =========================================================
if isprop(app,'UIFigure') && ...
        ~isempty(app.UIFigure) && ...
        isvalid(app.UIFigure)

    app.KineticTable_2.Data = Tdata;

    % Display names shown in the GUI
    app.KineticTable_2.ColumnName = { ...
        'Temperature (°C)', ...
        'Time (s)', ...
        'Moisture (kg/kg d.b.)'};

    app.KineticTable_2.RowName = {};

end


% =========================================================
% Plot drying curves
% =========================================================
ax = app.ArrheniusAxes;

cla(ax);
hold(ax,'on');

temps = unique(Tdata.Temperature_C,'sorted');

markers = {'o','s','d','^','v','>','<','p','h','x','+','*'};

for i = 1:numel(temps)

    Tk = Tdata(Tdata.Temperature_C == temps(i), :);

    ok = isfinite(Tk.time_s) & ...
         isfinite(Tk.moisture_g_g);

    Tk = Tk(ok,:);

    if isempty(Tk)
        continue;
    end

    [tSorted, idx] = sort(Tk.time_s);
    xSorted = Tk.moisture_g_g(idx);

    mk = markers{mod(i-1, numel(markers)) + 1};

    plot(ax, ...
        tSorted, ...
        xSorted, ...
        '-', ...
        'Marker', mk, ...
        'LineWidth', 1.2, ...
        'MarkerSize', 6);

end

grid(ax,'on');

xlabel(ax,'Time (s)');

ylabel(ax,'Moisture, X (kg/kg d.b.)');

title(ax,'Drying curves');

legend(ax, ...
    string(temps) + " °C", ...
    'Location','best');

hold(ax,'off');


% =========================================================
% Parse TXT / CSV kinetic data
% =========================================================
function Tout = parse_moisture_txt(fp)

    raw = readlines(fp);
    raw = strip(raw);
    raw(raw == "") = [];

    tempC = NaN;

    outTemp = [];
    outTime = [];
    outX = [];

    for ii = 1:numel(raw)

        s = raw(ii);

        % Detect temperature line
        tok = regexp( ...
            s, ...
            '(-?\d+(\.\d+)?)\s*°?\s*C', ...
            'tokens', ...
            'once');

        if ~isempty(tok)

            tempC = str2double(tok{1});
            continue;

        end

        % Ignore header lines
        if contains(lower(s),'time') && ...
                contains(lower(s),'moisture')

            continue;

        end

        % Extract numeric values
        nums = regexp( ...
            s, ...
            '(-?\d+(\.\d+)?([eE][-+]?\d+)?)', ...
            'match');

        if numel(nums) >= 2 && isfinite(tempC)

            tVal = str2double(nums{1});
            xVal = str2double(nums{2});

            if isfinite(tVal) && isfinite(xVal)

                outTemp(end+1,1) = tempC; %#ok<AGROW>
                outTime(end+1,1) = tVal;  %#ok<AGROW>
                outX(end+1,1)    = xVal;  %#ok<AGROW>

            end

        end

    end

    if isempty(outTime)

        error( ...
            'No data points detected. Check file format.');

    end

    % Internal variable names used by the App
    Tout = table( ...
        outTemp, ...
        outTime, ...
        outX, ...
        'VariableNames', { ...
            'Temperature_C', ...
            'time_s', ...
            'moisture_g_g'});

end


% =========================================================
% Parse Excel kinetic data
% =========================================================
function Tout = parse_moisture_excel(fp)

    Tout = readtable(fp);

    % Internal variable names required by the App
    req = { ...
        'Temperature_C', ...
        'time_s', ...
        'moisture_g_g'};

    if ~all(ismember(req, Tout.Properties.VariableNames))

        error( ...
            'Excel must contain columns: %s', ...
            strjoin(req, ', '));

    end

    Tout = Tout(:, req);

    ok = ...
        isfinite(Tout.Temperature_C) & ...
        isfinite(Tout.time_s) & ...
        isfinite(Tout.moisture_g_g);

    Tout = Tout(ok,:);

    if isempty(Tout)

        error( ...
            'Excel file does not contain valid numeric data.');

    end

end


        end

        % Button pushed function: FitKineticsButton
        function FitKineticsButtonPushed(app, event)

if isempty(app.ImportedKineticData) || ~istable(app.ImportedKineticData)
    uialert(app.UIFigure,'No data loaded. Import data first.','Fit error');
    return;
end

T = app.ImportedKineticData;

req = {'Temperature_C','time_s','moisture_g_g'};
if ~all(ismember(req, T.Properties.VariableNames))
    uialert(app.UIFigure,...
        'Data must contain Temperature_C, time_s, moisture_g_g.',...
        'Fit error');
    return;
end

model = string(app.ModelDropDown.Value);

temps = unique(T.Temperature_C);
temps = sort(temps);

Temperature_C = [];
k  = [];
k1 = [];
k2 = [];
a  = [];
b  = [];
c  = [];
n  = [];
Xe = [];
RMSE = [];
R2 = [];

for i = 1:numel(temps)

    Tk = T(T.Temperature_C == temps(i), :);

    t = Tk.time_s;
    X = Tk.moisture_g_g;

    ok = isfinite(t) & isfinite(X);
    t = t(ok);
    X = X(ok);

    if numel(t) < 4
        warning('Temperature %.2f °C skipped: insufficient valid points.', temps(i));
        continue;
    end

    [t, idx] = sort(t);
    X = X(idx);

    [t, ia] = unique(t, 'stable');
    X = X(ia);

    if numel(t) < 4
        warning('Temperature %.2f °C skipped: insufficient unique time points.', temps(i));
        continue;
    end

    X0 = X(1);
    Xe0 = min(X);

    denom = X0 - Xe0;
    if ~isfinite(denom) || abs(denom) < 1e-12
        warning('Temperature %.2f °C skipped: invalid MR denominator.', temps(i));
        continue;
    end

    MR = (X - Xe0) / denom;
    MR = max(min(MR, 1), 0);

    if numel(unique(round(MR,10))) < 3
        warning('Temperature %.2f °C skipped: insufficient MR variation.', temps(i));
        continue;
    end

    try
        switch model

            case "Newton"
                % MR = exp(-k t)
                p0 = log(1e-3);
                obj = @(p) sum((MR - exp(-exp(p(1))*t)).^2);

                p = fminsearch(obj, p0, optimset('Display','off'));

                kHat  = exp(p(1));
                k1Hat = NaN;
                k2Hat = NaN;
                aHat  = NaN;
                bHat  = NaN;
                cHat  = NaN;
                nHat  = NaN;

                MRhat = exp(-kHat*t);

            case "Page"
                % MR = exp(-k t^n)
                p0 = [log(1e-3), log(1.2)];
                obj = @(p) sum((MR - exp(-exp(p(1)) * t.^exp(p(2)))).^2);

                p = fminsearch(obj, p0, optimset('Display','off'));

                kHat  = exp(p(1));
                k1Hat = NaN;
                k2Hat = NaN;
                aHat  = NaN;
                bHat  = NaN;
                cHat  = NaN;
                nHat  = exp(p(2));

                MRhat = exp(-kHat * t.^nHat);

            case "Modified Page"
                % MR = exp(-(k t)^n)
                p0 = [log(1e-3), log(1.2)];
                obj = @(p) sum((MR - exp(-(exp(p(1))*t).^exp(p(2)))).^2);

                p = fminsearch(obj, p0, optimset('Display','off'));

                kHat  = exp(p(1));
                k1Hat = NaN;
                k2Hat = NaN;
                aHat  = NaN;
                bHat  = NaN;
                cHat  = NaN;
                nHat  = exp(p(2));

                MRhat = exp(-(kHat*t).^nHat);

            case "Henderson-Pabis"
                % MR = a exp(-k t)
                p0 = [log(1e-3), log(1.0)];
                obj = @(p) sum((MR - exp(p(2)) .* exp(-exp(p(1))*t)).^2);

                p = fminsearch(obj, p0, optimset('Display','off'));

                kHat  = exp(p(1));
                k1Hat = NaN;
                k2Hat = NaN;
                aHat  = exp(p(2));
                bHat  = NaN;
                cHat  = NaN;
                nHat  = NaN;

                MRhat = aHat .* exp(-kHat*t);

            case "Logarithmic"
                % MR = a exp(-k t) + c
                p0 = [log(1e-3), log(1.0), 0.0];
                obj = @(p) sum((MR - (exp(p(2)) .* exp(-exp(p(1))*t) + p(3))).^2);

                p = fminsearch(obj, p0, optimset('Display','off'));

                kHat  = exp(p(1));
                k1Hat = NaN;
                k2Hat = NaN;
                aHat  = exp(p(2));
                bHat  = NaN;
                cHat  = p(3);
                nHat  = NaN;

                MRhat = aHat .* exp(-kHat*t) + cHat;

            case "Two-Term"
                % MR = a exp(-k1 t) + b exp(-k2 t)
                p0 = [log(0.5), log(1e-3), log(0.5), log(1e-4)];
                obj = @(p) sum((MR - ...
                    (exp(p(1)) .* exp(-exp(p(2))*t) + ...
                     exp(p(3)) .* exp(-exp(p(4))*t))).^2);

                p = fminsearch(obj, p0, optimset('Display','off'));

                aHat  = exp(p(1));
                k1Hat = exp(p(2));
                bHat  = exp(p(3));
                k2Hat = exp(p(4));
                kHat  = NaN;
                cHat  = NaN;
                nHat  = NaN;

                MRhat = aHat .* exp(-k1Hat*t) + bHat .* exp(-k2Hat*t);

            case "Wang-Singh"
                % MR = 1 + a t + b t^2
                p0 = [-1e-4, 1e-8];
                obj = @(p) sum((MR - (1 + p(1)*t + p(2)*t.^2)).^2);

                p = fminsearch(obj, p0, optimset('Display','off'));

                kHat  = NaN;
                k1Hat = NaN;
                k2Hat = NaN;
                aHat  = p(1);
                bHat  = p(2);
                cHat  = NaN;
                nHat  = NaN;

                MRhat = 1 + aHat*t + bHat*t.^2;

            case "Midilli-Kucuk"
                % MR = a exp(-k t^n) + b t
                p0 = [log(1.0), log(1e-3), log(1.2), 0.0];
                obj = @(p) sum((MR - ...
                    (exp(p(1)) .* exp(-exp(p(2))*t.^exp(p(3))) + p(4)*t)).^2);

                p = fminsearch(obj, p0, optimset('Display','off'));

                aHat  = exp(p(1));
                kHat  = exp(p(2));
                nHat  = exp(p(3));
                bHat  = p(4);
                k1Hat = NaN;
                k2Hat = NaN;
                cHat  = NaN;

                MRhat = aHat .* exp(-kHat*t.^nHat) + bHat*t;

            otherwise
                uialert(app.UIFigure,'Unknown model in ModelDropDown.','Fit error');
                return;
        end

    catch ME
        warning('Fit failed at T = %.2f °C: %s', temps(i), ME.message);
        continue;
    end

    Xhat = Xe0 + MRhat * (X0 - Xe0);

    rmse = sqrt(mean((X - Xhat).^2));

    ss_res = sum((X - Xhat).^2);
    ss_tot = sum((X - mean(X)).^2);
    r2 = 1 - ss_res / max(ss_tot, eps);

    Temperature_C(end+1,1) = temps(i); %#ok<AGROW>
    k(end+1,1)             = kHat;     %#ok<AGROW>
    k1(end+1,1)            = k1Hat;    %#ok<AGROW>
    k2(end+1,1)            = k2Hat;    %#ok<AGROW>
    a(end+1,1)             = aHat;     %#ok<AGROW>
    b(end+1,1)             = bHat;     %#ok<AGROW>
    c(end+1,1)             = cHat;     %#ok<AGROW>
    n(end+1,1)             = nHat;     %#ok<AGROW>
    Xe(end+1,1)            = Xe0;      %#ok<AGROW>
    RMSE(end+1,1)          = rmse;     %#ok<AGROW>
    R2(end+1,1)            = r2;       %#ok<AGROW>
end

if isempty(Temperature_C)
    uialert(app.UIFigure,...
        'No valid fits were obtained. Check the imported data and selected model.',...
        'Fit error');
    return;
end

FitResults = table(Temperature_C, k, k1, k2, a, b, c, n, Xe, RMSE, R2);

D = cell(height(FitResults), 12);
modelName = char(model);

for r = 1:height(FitResults)
    D{r,1}  = modelName;
    D{r,2}  = FitResults.Temperature_C(r);
    D{r,3}  = FitResults.k(r);
    D{r,4}  = FitResults.k1(r);
    D{r,5}  = FitResults.k2(r);
    D{r,6}  = FitResults.a(r);
    D{r,7}  = FitResults.b(r);
    D{r,8}  = FitResults.c(r);
    D{r,9}  = FitResults.n(r);
    D{r,10} = FitResults.Xe(r);
    D{r,11} = FitResults.RMSE(r);
    D{r,12} = FitResults.R2(r);
end

app.KineticTable.Data = D;
app.KineticTable.ColumnName = {'Model','T (°C)','k','k1','k2','a','b','c','n','Xe','RMSE','R2'};
app.KineticTable.RowName = {};

msg = sprintf('%d temperature fits completed successfully.', height(FitResults));
uialert(app.UIFigure, msg, 'Kinetic fit');


        end

        % Button pushed function: psicromety
        function psicrometyButtonPushed(app, event)

            To= app.AmbientTemperature.Value ;
            HRa= app.AmbientRelativeHumidity.Value/100;

            % B) Si quieres carta + punto (descomenta y ajusta):
            showPsychrometricChartDialog(app.UIFigure, ...
                'PlotState', true, 'StateMode', 'T_RH', ...
                'Tdb_C', To, 'RH_frac', HRa);
        end

        % Callback function
        function ReportButtonPushed(app, event)

            app.generateFullPDFReport();
        end

        % Callback function
        function SaveMenuSelected(app, event)
         
            caseData = struct();

            % =========================
            % Metadata
            % =========================
            caseData.Metadata.SoftwareVersion = 'V1.0';
            caseData.Metadata.SavedDate = datestr(now, 'yyyy-mm-dd HH:MM:SS');
            caseData.Metadata.MATLABVersion = version;

            % =========================
            % Process inputs
            % =========================
            caseData.Process.Free = app.Free.Value;
            caseData.Process.InitialMouisture = app.InitialMouisture.Value;
            caseData.Process.FinalMouisturetarget = app.FinalMouisturetarget.Value;
            caseData.Process.SolidTemperature = app.SolidTemperature.Value;
            caseData.Process.GasInletTemperature = app.GasInletTemperature.Value;
            caseData.Process.MaxSolidTemperature = app.MaxSolidTemperature.Value;
            caseData.Process.FuelType = app.FuelTypeDropDown.Value;

            % =========================
            % Operating conditions
            % =========================
            caseData.Operation.fractionalheatloss = app.fractionalheatloss.Value;
            caseData.Operation.phiRet = app.phiRet.Value;
            caseData.Operation.Vgas_teo = app.Vgas_teo.Value;
            caseData.Operation.PeripheralVelocity = app.PeripheralVelocity.Value;
            caseData.Operation.FillingDegree = app.FillingDegree.Value;

            % =========================
            % Solid properties
            % =========================
            caseData.Solid.Ksolid = app.Ksolid.Value;
            caseData.Solid.Cpsolid = app.Cpsolid.Value;
            caseData.Solid.TrueDensity = app.TrueDensity.Value;
            caseData.Solid.Bulksdensity = app.Bulksdensity.Value;
            caseData.Solid.Es = app.Es.Value;
            caseData.Solid.ParticleDiameter = app.ParticleDiameter.Value;

            % =========================
            % Ambient air
            % =========================
            caseData.Air.AmbientTemperature = app.AmbientTemperature.Value;
            caseData.Air.AmbientRelativeHumidity = app.AmbientRelativeHumidity.Value;
            caseData.Air.Altitude = app.Altitude.Value;
            caseData.Air.Cp_aire_box = app.Cp_aire_box.Value;
            caseData.Air.K_aire = app.K_aire.Value;
            caseData.Air.den_aire = app.den_aire.Value;
            caseData.Air.w_aire = app.w_aire.Value;
            caseData.Air.h_aire = app.h_aire.Value;

            % =========================
            % Drying gas
            % =========================
            caseData.Gas.Tm_gas = app.Tm_gas.Value;
            caseData.Gas.Cpgas = app.Cpgas.Value;
            caseData.Gas.Kgas = app.Kgas.Value;
            caseData.Gas.Density_gas = app.Density_gas.Value;
            caseData.Gas.viscosidad_D_gas = app.viscosidad_D_gas.Value;
            caseData.Gas.Egas = app.Egas.Value;

            % =========================
            % Kinetic parameters
            % =========================
            caseData.Kinetics.ModelDropDown = app.ModelDropDown.Value;
            caseData.Kinetics.paramet_E = app.paramet_E.Value;
            caseData.Kinetics.paramet_R = app.paramet_R.Value;
            caseData.Kinetics.paramet_A = app.paramet_A.Value;
            caseData.Kinetics.paramet_Heq = app.paramet_Heq.Value;

            % =========================
            % Solver / design
            % =========================
            caseData.Solver.RateMgs = app.RateMgs.Value;
            caseData.Solver.Diametro_test = app.Diametro_test.Value;
            caseData.Solver.Stip = app.Stip.Value;
            caseData.Solver.Lm_ax = app.Lm_ax.Value;
            caseData.Solver.Tolerance = app.Tolerance.Value;

            % =========================
            % Default folder for saved cases
            % =========================
            appPath = fileparts(mfilename('fullpath'));
            projectRoot = fileparts(appPath);
            defaultCaseFolder = fullfile(projectRoot, 'data', 'saved_case');

            if ~exist(defaultCaseFolder, 'dir')
                mkdir(defaultCaseFolder);
            end

            defaultFile = fullfile(defaultCaseFolder, 'caseData.mat');

            % Guardar archivo
            [file, path] = uiputfile('*.mat', 'Save case as', defaultFile);

            if isequal(file,0) || isequal(path,0)
                return;
            end

            save(fullfile(path, file), 'caseData');

            uialert(app.UIFigure, 'Case saved successfully.', 'Save case');


        end

        % Callback function
        function ImporMenuSelected(app, event)
            appPath = fileparts(mfilename('fullpath'));
            projectRoot = fileparts(appPath);
            defaultCaseFolder = fullfile(projectRoot, 'data', 'saved_case');

            if ~exist(defaultCaseFolder, 'dir')
                mkdir(defaultCaseFolder);
            end

            [file, path] = uigetfile(fullfile(defaultCaseFolder, '*.mat'), 'Load saved case');

            if isequal(file,0) || isequal(path,0)
                return;
            end

            S = load(fullfile(path, file));

            if ~isfield(S, 'caseData')
                uialert(app.UIFigure, 'The selected file does not contain caseData.', 'Load error');
                return;
            end

            caseData = S.caseData;


            % =========================
            % Process inputs
            % =========================
            if isfield(caseData, 'Process')
                if isfield(caseData.Process, 'Free'), app.Free.Value = caseData.Process.Free; end
                if isfield(caseData.Process, 'InitialMouisture'), app.InitialMouisture.Value = caseData.Process.InitialMouisture; end
                if isfield(caseData.Process, 'FinalMouisturetarget'), app.FinalMouisturetarget.Value = caseData.Process.FinalMouisturetarget; end
                if isfield(caseData.Process, 'SolidTemperature'), app.SolidTemperature.Value = caseData.Process.SolidTemperature; end
                if isfield(caseData.Process, 'GasInletTemperature'), app.GasInletTemperature.Value = caseData.Process.GasInletTemperature; end
                if isfield(caseData.Process, 'MaxSolidTemperature'), app.MaxSolidTemperature.Value = caseData.Process.MaxSolidTemperature; end
                if isfield(caseData.Process, 'FuelType'), app.FuelTypeDropDown.Value = caseData.Process.FuelType; end
            end

            % =========================
            % Operating conditions
            % =========================
            if isfield(caseData, 'Operation')
                if isfield(caseData.Operation, 'fractionalheatloss'), app.fractionalheatloss.Value = caseData.Operation.fractionalheatloss; end
                if isfield(caseData.Operation, 'phiRet'), app.phiRet.Value = caseData.Operation.phiRet; end
                if isfield(caseData.Operation, 'Vgas_teo'), app.Vgas_teo.Value = caseData.Operation.Vgas_teo; end
                if isfield(caseData.Operation, 'PeripheralVelocity'), app.PeripheralVelocity.Value = caseData.Operation.PeripheralVelocity; end
                if isfield(caseData.Operation, 'FillingDegree'), app.FillingDegree.Value = caseData.Operation.FillingDegree; end
            end

            % =========================
            % Solid properties
            % =========================
            if isfield(caseData, 'Solid')
                if isfield(caseData.Solid, 'Ksolid'), app.Ksolid.Value = caseData.Solid.Ksolid; end
                if isfield(caseData.Solid, 'Cpsolid'), app.Cpsolid.Value = caseData.Solid.Cpsolid; end
                if isfield(caseData.Solid, 'TrueDensity'), app.TrueDensity.Value = caseData.Solid.TrueDensity; end
                if isfield(caseData.Solid, 'Bulksdensity'), app.Bulksdensity.Value = caseData.Solid.Bulksdensity; end
                if isfield(caseData.Solid, 'Es'), app.Es.Value = caseData.Solid.Es; end
                if isfield(caseData.Solid, 'ParticleDiameter'), app.ParticleDiameter.Value = caseData.Solid.ParticleDiameter; end
            end

            % =========================
            % Ambient air
            % =========================
            if isfield(caseData, 'Air')
                if isfield(caseData.Air, 'AmbientTemperature'), app.AmbientTemperature.Value = caseData.Air.AmbientTemperature; end
                if isfield(caseData.Air, 'AmbientRelativeHumidity'), app.AmbientRelativeHumidity.Value = caseData.Air.AmbientRelativeHumidity; end
                if isfield(caseData.Air, 'Altitude'), app.Altitude.Value = caseData.Air.Altitude; end
                if isfield(caseData.Air, 'Cp_aire_box'), app.Cp_aire_box.Value = caseData.Air.Cp_aire_box; end
                if isfield(caseData.Air, 'K_aire'), app.K_aire.Value = caseData.Air.K_aire; end
                if isfield(caseData.Air, 'den_aire'), app.den_aire.Value = caseData.Air.den_aire; end
                if isfield(caseData.Air, 'w_aire'), app.w_aire.Value = caseData.Air.w_aire; end
                if isfield(caseData.Air, 'h_aire'), app.h_aire.Value = caseData.Air.h_aire; end
            end

            % =========================
            % Drying gas
            % =========================
            if isfield(caseData, 'Gas')
                if isfield(caseData.Gas, 'Tm_gas'), app.Tm_gas.Value = caseData.Gas.Tm_gas; end
                if isfield(caseData.Gas, 'Cpgas'), app.Cpgas.Value = caseData.Gas.Cpgas; end
                if isfield(caseData.Gas, 'Kgas'), app.Kgas.Value = caseData.Gas.Kgas; end
                if isfield(caseData.Gas, 'Density_gas'), app.Density_gas.Value = caseData.Gas.Density_gas; end
                if isfield(caseData.Gas, 'viscosidad_D_gas'), app.viscosidad_D_gas.Value = caseData.Gas.viscosidad_D_gas; end
                if isfield(caseData.Gas, 'Egas'), app.Egas.Value = caseData.Gas.Egas; end
            end

            % =========================
            % Kinetic parameters
            % =========================
            if isfield(caseData, 'Kinetics')
                if isfield(caseData.Kinetics, 'ModelDropDown'), app.ModelDropDown.Value = caseData.Kinetics.ModelDropDown; end
                if isfield(caseData.Kinetics, 'paramet_E'), app.paramet_E.Value = caseData.Kinetics.paramet_E; end
                if isfield(caseData.Kinetics, 'paramet_R'), app.paramet_R.Value = caseData.Kinetics.paramet_R; end
                if isfield(caseData.Kinetics, 'paramet_A'), app.paramet_A.Value = caseData.Kinetics.paramet_A; end
                if isfield(caseData.Kinetics, 'paramet_Heq'), app.paramet_Heq.Value = caseData.Kinetics.paramet_Heq; end
            end

            % =========================
            % Solver / design
            % =========================
            if isfield(caseData, 'Solver')
                if isfield(caseData.Solver, 'RateMgs'), app.RateMgs.Value = caseData.Solver.RateMgs; end
                if isfield(caseData.Solver, 'Diametro_test'), app.Diametro_test.Value = caseData.Solver.Diametro_test; end
                if isfield(caseData.Solver, 'Stip'), app.Stip.Value = caseData.Solver.Stip; end
                if isfield(caseData.Solver, 'Lm_ax'), app.Lm_ax.Value = caseData.Solver.Lm_ax; end
                if isfield(caseData.Solver, 'Tolerance'), app.Tolerance.Value = caseData.Solver.Tolerance; end
            end

            uialert(app.UIFigure, 'Case loaded successfully.', 'Load case');

        end

        % Callback function
        function ReportMenuSelected(app, event)
            app.generateFullPDFReport();
        end

        % Callback function: PushTool2
        function PushTool2Clicked(app, event)

           caseData = struct();

% =========================
% Metadata
% =========================
caseData.Metadata.SoftwareVersion = 'V1.0';
caseData.Metadata.SavedDate = datestr(now, 'yyyy-mm-dd HH:MM:SS');
caseData.Metadata.MATLABVersion = version;

% =========================
% Process inputs
% =========================
caseData.Process.Free = app.Free.Value;
caseData.Process.InitialMouisture = app.InitialMouisture.Value;
caseData.Process.FinalMouisturetarget = app.FinalMouisturetarget.Value;
caseData.Process.SolidTemperature = app.SolidTemperature.Value;
caseData.Process.GasInletTemperature = app.GasInletTemperature.Value;
caseData.Process.MaxSolidTemperature = app.MaxSolidTemperature.Value;
caseData.Process.FuelType = app.FuelTypeDropDown.Value;

% =========================
% Operating conditions
% =========================
caseData.Operation.fractionalheatloss = app.fractionalheatloss.Value;
caseData.Operation.phiRet = app.phiRet.Value;
caseData.Operation.Vgas_teo = app.Vgas_teo.Value;
caseData.Operation.PeripheralVelocity = app.PeripheralVelocity.Value;
caseData.Operation.FillingDegree = app.FillingDegree.Value;

% =========================
% Solid properties
% =========================
caseData.Solid.Ksolid = app.Ksolid.Value;
caseData.Solid.Cpsolid = app.Cpsolid.Value;
caseData.Solid.TrueDensity = app.TrueDensity.Value;
caseData.Solid.Bulksdensity = app.Bulksdensity.Value;
caseData.Solid.Es = app.Es.Value;
caseData.Solid.ParticleDiameter = app.ParticleDiameter.Value;

% =========================
% Ambient air
% =========================
caseData.Air.AmbientTemperature = app.AmbientTemperature.Value;
caseData.Air.AmbientRelativeHumidity = app.AmbientRelativeHumidity.Value;
caseData.Air.Altitude = app.Altitude.Value;
caseData.Air.Cp_aire_box = app.Cp_aire_box.Value;
caseData.Air.K_aire = app.K_aire.Value;
caseData.Air.den_aire = app.den_aire.Value;
caseData.Air.w_aire = app.w_aire.Value;
caseData.Air.h_aire = app.h_aire.Value;

% =========================
% Drying gas
% =========================
caseData.Gas.Tm_gas = app.Tm_gas.Value;
caseData.Gas.Cpgas = app.Cpgas.Value;
caseData.Gas.Kgas = app.Kgas.Value;
caseData.Gas.Density_gas = app.Density_gas.Value;
caseData.Gas.viscosidad_D_gas = app.viscosidad_D_gas.Value;
caseData.Gas.Egas = app.Egas.Value;

% =========================
% Kinetic parameters
% =========================
caseData.Kinetics.ModelDropDown = app.ModelDropDown.Value;
caseData.Kinetics.paramet_E = app.paramet_E.Value;
caseData.Kinetics.paramet_R = app.paramet_R.Value;
caseData.Kinetics.paramet_A = app.paramet_A.Value;
caseData.Kinetics.paramet_Heq = app.paramet_Heq.Value;

% =========================
% Solver / design
% =========================
caseData.Solver.RateMgs = app.RateMgs.Value;
caseData.Solver.Diametro_test = app.Diametro_test.Value;
caseData.Solver.Stip = app.Stip.Value;
caseData.Solver.Lm_ax = app.Lm_ax.Value;
caseData.Solver.Tolerance = app.Tolerance.Value;

% Save file
appPath = fileparts(mfilename('fullpath'));
projectRoot = fileparts(appPath);
defaultCaseFolder = fullfile(projectRoot, 'data', 'saved_case');

if ~exist(defaultCaseFolder, 'dir')
    mkdir(defaultCaseFolder);
end

[file, path] = uiputfile(fullfile(defaultCaseFolder, '*.mat'), 'Save case as');

if isequal(file,0) || isequal(path,0)
    return;
end

save(fullfile(path, file), 'caseData');

uialert(app.UIFigure, 'Case saved successfully.', 'Save case');


        end

        % Callback function: PushTool3
        function PushTool3Clicked(app, event)
           appPath = fileparts(mfilename('fullpath'));
           projectRoot = fileparts(appPath);
           defaultCaseFolder = fullfile(projectRoot, 'data', 'saved_case');

           if ~exist(defaultCaseFolder, 'dir')
               mkdir(defaultCaseFolder);
           end

           [file, path] = uigetfile(fullfile(defaultCaseFolder, '*.mat'), 'Load saved case');

           if isequal(file,0) || isequal(path,0)
               return;
           end

           S = load(fullfile(path, file));

           if ~isfield(S, 'caseData')
               uialert(app.UIFigure, 'The selected file does not contain caseData.', 'Load error');
               return;
           end

           caseData = S.caseData;

            % =========================
            % Process inputs
            % =========================
            if isfield(caseData, 'Process')
                if isfield(caseData.Process, 'Free'), app.Free.Value = caseData.Process.Free; end
                if isfield(caseData.Process, 'InitialMouisture'), app.InitialMouisture.Value = caseData.Process.InitialMouisture; end
                if isfield(caseData.Process, 'FinalMouisturetarget'), app.FinalMouisturetarget.Value = caseData.Process.FinalMouisturetarget; end
                if isfield(caseData.Process, 'SolidTemperature'), app.SolidTemperature.Value = caseData.Process.SolidTemperature; end
                if isfield(caseData.Process, 'GasInletTemperature'), app.GasInletTemperature.Value = caseData.Process.GasInletTemperature; end
                if isfield(caseData.Process, 'MaxSolidTemperature'), app.MaxSolidTemperature.Value = caseData.Process.MaxSolidTemperature; end
                if isfield(caseData.Process, 'FuelType'), app.FuelTypeDropDown.Value = caseData.Process.FuelType; end
            end

            % =========================
            % Operating conditions
            % =========================
            if isfield(caseData, 'Operation')
                if isfield(caseData.Operation, 'fractionalheatloss'), app.fractionalheatloss.Value = caseData.Operation.fractionalheatloss; end
                if isfield(caseData.Operation, 'phiRet'), app.phiRet.Value = caseData.Operation.phiRet; end
                if isfield(caseData.Operation, 'Vgas_teo'), app.Vgas_teo.Value = caseData.Operation.Vgas_teo; end
                if isfield(caseData.Operation, 'PeripheralVelocity'), app.PeripheralVelocity.Value = caseData.Operation.PeripheralVelocity; end
                if isfield(caseData.Operation, 'FillingDegree'), app.FillingDegree.Value = caseData.Operation.FillingDegree; end
            end

            % =========================
            % Solid properties
            % =========================
            if isfield(caseData, 'Solid')
                if isfield(caseData.Solid, 'Ksolid'), app.Ksolid.Value = caseData.Solid.Ksolid; end
                if isfield(caseData.Solid, 'Cpsolid'), app.Cpsolid.Value = caseData.Solid.Cpsolid; end
                if isfield(caseData.Solid, 'TrueDensity'), app.TrueDensity.Value = caseData.Solid.TrueDensity; end
                if isfield(caseData.Solid, 'Bulksdensity'), app.Bulksdensity.Value = caseData.Solid.Bulksdensity; end
                if isfield(caseData.Solid, 'Es'), app.Es.Value = caseData.Solid.Es; end
                if isfield(caseData.Solid, 'ParticleDiameter'), app.ParticleDiameter.Value = caseData.Solid.ParticleDiameter; end
            end

            % =========================
            % Ambient air
            % =========================
            if isfield(caseData, 'Air')
                if isfield(caseData.Air, 'AmbientTemperature'), app.AmbientTemperature.Value = caseData.Air.AmbientTemperature; end
                if isfield(caseData.Air, 'AmbientRelativeHumidity'), app.AmbientRelativeHumidity.Value = caseData.Air.AmbientRelativeHumidity; end
                if isfield(caseData.Air, 'Altitude'), app.Altitude.Value = caseData.Air.Altitude; end
                if isfield(caseData.Air, 'Cp_aire_box'), app.Cp_aire_box.Value = caseData.Air.Cp_aire_box; end
                if isfield(caseData.Air, 'K_aire'), app.K_aire.Value = caseData.Air.K_aire; end
                if isfield(caseData.Air, 'den_aire'), app.den_aire.Value = caseData.Air.den_aire; end
                if isfield(caseData.Air, 'w_aire'), app.w_aire.Value = caseData.Air.w_aire; end
                if isfield(caseData.Air, 'h_aire'), app.h_aire.Value = caseData.Air.h_aire; end
            end

            % =========================
            % Drying gas
            % =========================
            if isfield(caseData, 'Gas')
                if isfield(caseData.Gas, 'Tm_gas'), app.Tm_gas.Value = caseData.Gas.Tm_gas; end
                if isfield(caseData.Gas, 'Cpgas'), app.Cpgas.Value = caseData.Gas.Cpgas; end
                if isfield(caseData.Gas, 'Kgas'), app.Kgas.Value = caseData.Gas.Kgas; end
                if isfield(caseData.Gas, 'Density_gas'), app.Density_gas.Value = caseData.Gas.Density_gas; end
                if isfield(caseData.Gas, 'viscosidad_D_gas'), app.viscosidad_D_gas.Value = caseData.Gas.viscosidad_D_gas; end
                if isfield(caseData.Gas, 'Egas'), app.Egas.Value = caseData.Gas.Egas; end
            end

            % =========================
            % Kinetic parameters
            % =========================
            if isfield(caseData, 'Kinetics')
                if isfield(caseData.Kinetics, 'ModelDropDown'), app.ModelDropDown.Value = caseData.Kinetics.ModelDropDown; end
                if isfield(caseData.Kinetics, 'paramet_E'), app.paramet_E.Value = caseData.Kinetics.paramet_E; end
                if isfield(caseData.Kinetics, 'paramet_R'), app.paramet_R.Value = caseData.Kinetics.paramet_R; end
                if isfield(caseData.Kinetics, 'paramet_A'), app.paramet_A.Value = caseData.Kinetics.paramet_A; end
                if isfield(caseData.Kinetics, 'paramet_Heq'), app.paramet_Heq.Value = caseData.Kinetics.paramet_Heq; end
            end

            % =========================
            % Solver / design
            % =========================
            if isfield(caseData, 'Solver')
                if isfield(caseData.Solver, 'RateMgs'), app.RateMgs.Value = caseData.Solver.RateMgs; end
                if isfield(caseData.Solver, 'Diametro_test'), app.Diametro_test.Value = caseData.Solver.Diametro_test; end
                if isfield(caseData.Solver, 'Stip'), app.Stip.Value = caseData.Solver.Stip; end
                if isfield(caseData.Solver, 'Lm_ax'), app.Lm_ax.Value = caseData.Solver.Lm_ax; end
                if isfield(caseData.Solver, 'Tolerance'), app.Tolerance.Value = caseData.Solver.Tolerance; end
            end

            uialert(app.UIFigure, 'Case loaded successfully.', 'Load case');
        end

        % Callback function: PushTool5
        function PushTool5Clicked(app, event)
            app.generateFullPDFReport();
        end

        % Callback function: PushTool4
        function PushTool4Clicked(app, event)
            
            appPath = fileparts(mfilename('fullpath'));
            pdfFile = fullfile(appPath, 'docs', 'User_Manual.pdf');

            if isfile(pdfFile)
                open(pdfFile);
            else
                uialert(app.UIFigure, ...
                    'The PDF help file was not found in the docs folder.', ...
                    'File Not Found');
            end
        end

        % Callback function: PushTool
        function PushToolClicked(app, event)
            choice = uiconfirm(app.UIFigure, ...
                ['A new project will clear the current inputs and results.' newline ...
                'Do you want to continue?'], ...
                'New project', ...
                'Options', {'Yes','No'}, ...
                'DefaultOption', 2, ...
                'CancelOption', 2);

            if ~strcmp(choice, 'Yes')
                return;
            end

            % Input tab
            app.Free.Value = 0;
            app.InitialMouisture.Value = 0;
            app.FinalMouisturetarget.Value = 0;
            app.SolidTemperature.Value = 0;
            app.GasInletTemperature.Value = 0;
            app.MaxSolidTemperature.Value = 0;

            % Operating conditions
            app.fractionalheatloss.Value = 0.1;
            app.phiRet.Value = 0.15;
            app.Vgas_teo.Value = 3;
            app.PeripheralVelocity.Value = 0.4;
            app.FillingDegree.Value = 0.15;
            app.FuelTypeDropDown.Value = 'natural gas';

            % Solid properties
            app.Ksolid.Value = 0;
            app.Cpsolid.Value = 0;
            app.TrueDensity.Value = 0;
            app.Bulksdensity.Value = 0;
            app.Es.Value = 0;
            app.ParticleDiameter.Value = 0;

            % Ambient air
            app.AmbientTemperature.Value = 22;
            app.AmbientRelativeHumidity.Value = 60;
            app.Altitude.Value = 1;
            app.Cp_aire_box.Value = 0;
            app.K_aire.Value = 0;
            app.den_aire.Value = 0;
            app.w_aire.Value = 0;
            app.h_aire.Value = 0;

            % Drying gas
            app.Tm_gas.Value = 0;
            app.Cpgas.Value = 0;
            app.Kgas.Value = 0;
            app.Density_gas.Value = 0;
            app.viscosidad_D_gas.Value = 0;
            app.Egas.Value = 0;

            % Kinetic parameters
            app.ModelDropDown.Value = 'Newton';
            app.paramet_E.Value = 0;
            app.paramet_R.Value = 0;
            app.paramet_A.Value = 0;
            app.paramet_Heq.Value = 0;

            % Solver / design
            app.RateMgs.Value = 0;
            app.Diametro_test.Value = 0;
            app.Stip.Value = 0.01;
            app.Lm_ax.Value = 60;
            app.Tolerance.Value = 1e-6;

            % Tables and plots
            app.UITableResults.Data = {};
            app.KineticTable_2.Data = {};

            app.TempButton.Value = true;
            app.HumidityButton.Value = false;
            app.T_x_M.Value = false;

            cla(app.UIAxes,'reset');
            grid(app.UIAxes,'on');
            xlabel(app.UIAxes,'X')
            ylabel(app.UIAxes,'Y')
            title(app.UIAxes,'')

            cla(app.ArrheniusAxes,'reset');
            grid(app.ArrheniusAxes,'on');
            xlabel(app.ArrheniusAxes,'X')
            ylabel(app.ArrheniusAxes,'Y')
            title(app.ArrheniusAxes,'')

            updateStatusLamps(app, [], [], []);

            % Optional: reset equation label
            app.KineticEquationLabel.Text = '$MR=\exp(-k\,t)$';
            app.KineticEquationLabel.Interpreter = 'latex';

           uialert(app.UIFigure, 'A new project has been created.', 'New project');
        end

        % Button pushed function: exportfig
        function exportfigButtonPushed(app, event)


            if isempty(app.UIAxes.Children)
                uialert(app.UIFigure, 'No figure available to export.', 'Export figure');
                return;
            end

            appPath = fileparts(which(class(app)));
            projectRoot = fileparts(appPath);
            defaultFigureFolder = fullfile(projectRoot, 'outputs', 'figures');

            if ~exist(defaultFigureFolder, 'dir')
                mkdir(defaultFigureFolder);
            end

            defaultFullName = fullfile(defaultFigureFolder, 'figure_export.png');

            [file, path] = uiputfile(defaultFullName, 'Save figure as');

            if isequal(file,0) || isequal(path,0)
                return;
            end

            fullName = fullfile(path, file);
            exportgraphics(app.UIAxes, fullName, 'Resolution', 300);
            uialert(app.UIFigure, 'Figure exported successfully.', 'Export figure');

        end

        % Button pushed function: exporttable
        function exporttableButtonPushed(app, event)


            T = app.UITableResults.Data;

            if isempty(T)
                uialert(app.UIFigure, 'No table data available to export.', 'Export table');
                return;
            end

            appPath = fileparts(which(class(app)));
projectRoot = fileparts(appPath);
defaultTableFolder = fullfile(projectRoot, 'outputs', 'tables');

if ~exist(defaultTableFolder, 'dir')
    mkdir(defaultTableFolder);
end

defaultFullName = fullfile(defaultTableFolder, 'results_table.xlsx');

[file, path] = uiputfile(defaultFullName, 'Save table as');

            if isequal(file,0) || isequal(path,0)
                return;
            end

            fullName = fullfile(path, file);

            if iscell(T)
                T = cell2table(T, 'VariableNames', {'Parameter','Value','Unit'});
            end

            writetable(T, fullName);
            uialert(app.UIFigure, 'Table exported successfully.', 'Export table');

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Toolbar = uitoolbar(app.UIFigure);
            app.PushTool = uipushtool(app.Toolbar);
            app.PushTool.Tooltip = {'New project'};
            app.PushTool.ClickedCallback = createCallbackFcn(app, @PushToolClicked, true);
            app.PushTool.Icon = fullfile(pathToMLAPP, 'icon', 'expediente.png');
            app.PushTool2 = uipushtool(app.Toolbar);
            app.PushTool2.Tooltip = {'Save'};
            app.PushTool2.ClickedCallback = createCallbackFcn(app, @PushTool2Clicked, true);
            app.PushTool2.Icon = fullfile(pathToMLAPP, 'icon', 'Save.png');
            app.PushTool2.Separator = 'on';
            app.PushTool3 = uipushtool(app.Toolbar);
            app.PushTool3.Tooltip = {'Import'};
            app.PushTool3.ClickedCallback = createCallbackFcn(app, @PushTool3Clicked, true);
            app.PushTool3.Icon = fullfile(pathToMLAPP, 'icon', 'importar.png');
            app.PushTool3.Separator = 'on';
            app.PushTool5 = uipushtool(app.Toolbar);
            app.PushTool5.Tooltip = {'Generate report'};
            app.PushTool5.ClickedCallback = createCallbackFcn(app, @PushTool5Clicked, true);
            app.PushTool5.Icon = fullfile(pathToMLAPP, 'icon', 'reporte.png');
            app.PushTool5.Separator = 'on';
            app.PushTool4 = uipushtool(app.Toolbar);
            app.PushTool4.Tooltip = {'Help'};
            app.PushTool4.ClickedCallback = createCallbackFcn(app, @PushTool4Clicked, true);
            app.PushTool4.Icon = fullfile(pathToMLAPP, 'icon', 'signo-de-interrogacion.png');
            app.PushTool4.Separator = 'on';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.InputTab = uitab(app.TabGroup);
            app.InputTab.Title = 'Input';
            app.InputPanel = uipanel(app.InputTab);
            app.InputPanel.Title = 'Input';
            app.CLabel = uilabel(app.InputPanel);
            app.CLabel.Text = '°C';
            app.CLabel_3 = uilabel(app.InputPanel);
            app.CLabel_3.Text = '°C';
            app.thLabel = uilabel(app.InputPanel);
            app.thLabel.Text = 't/h';
            app.FeedrateLabel = uilabel(app.InputPanel);
            app.FeedrateLabel.Text = 'Feed rate';
            app.Free = uieditfield(app.InputPanel, 'numeric');
            app.InitialmoistureLabel = uilabel(app.InputPanel);
            app.InitialmoistureLabel.Text = 'Initial moisture (% d.b.)';
            app.InitialMouisture = uieditfield(app.InputPanel, 'numeric');
            app.InitialsolidtemperatureLabel = uilabel(app.InputPanel);
            app.InitialsolidtemperatureLabel.Text = 'Initial solid temperature';
            app.SolidTemperature = uieditfield(app.InputPanel, 'numeric');
            app.MaxinletgasTemperatLabel = uilabel(app.InputPanel);
            app.MaxinletgasTemperatLabel.Text = 'Max. inlet gas temp.';
            app.GasInletTemperature = uieditfield(app.InputPanel, 'numeric');
            app.Label = uilabel(app.InputPanel);
            app.Label.Text = '%';
            app.MaximumsolidtemperatureLabel = uilabel(app.InputPanel);
            app.MaximumsolidtemperatureLabel.Text = 'Maximum solid temperature';
            app.MaxSolidTemperature = uieditfield(app.InputPanel, 'numeric');
            app.CLabel_4 = uilabel(app.InputPanel);
            app.CLabel_4.Text = '°C';
            app.TargetlMouistureLabel = uilabel(app.InputPanel);
            app.TargetlMouistureLabel.Text = 'Target moisture (% d.b.)';
            app.FinalMouisturetarget = uieditfield(app.InputPanel, 'numeric');
            app.Label_4 = uilabel(app.InputPanel);
            app.Label_4.Text = '%';
            app.OperatingConditionsPanel = uipanel(app.InputTab);
            app.OperatingConditionsPanel.Title = 'Operating Conditions';
            app.FgasLabel_2 = uilabel(app.OperatingConditionsPanel);
            app.FgasLabel_2.Text = 'Heat-loss fraction';
            app.fractionalheatloss = uieditfield(app.OperatingConditionsPanel, 'numeric');
            app.fractionalheatloss.Value = 0.1;
            app.FgasLabel_3 = uilabel(app.OperatingConditionsPanel);
            app.FgasLabel_3.Text = 'Holdup without gas flow';
            app.phiRet = uieditfield(app.OperatingConditionsPanel, 'numeric');
            app.phiRet.Value = 0.15;
            app.msLabel = uilabel(app.OperatingConditionsPanel);
            app.msLabel.Text = 'm/s';
            app.msLabel_2 = uilabel(app.OperatingConditionsPanel);
            app.msLabel_2.Text = 'm/s';
            app.PeripheralvelocityLabel = uilabel(app.OperatingConditionsPanel);
            app.PeripheralvelocityLabel.Text = 'Peripheral velocity';
            app.PeripheralVelocity = uispinner(app.OperatingConditionsPanel);
            app.PeripheralVelocity.Step = 0.1;
            app.PeripheralVelocity.Limits = [0.1 0.8];
            app.PeripheralVelocity.Value = 0.4;
            app.GasVelocityLabel = uilabel(app.OperatingConditionsPanel);
            app.GasVelocityLabel.Text = 'Design gas velocity';
            app.Vgas_teo = uispinner(app.OperatingConditionsPanel);
            app.Vgas_teo.Step = 0.5;
            app.Vgas_teo.Limits = [1 8];
            app.Vgas_teo.Value = 3;
            app.FillingDegreeLabel = uilabel(app.OperatingConditionsPanel);
            app.FillingDegreeLabel.Text = 'Filling degree';
            app.FillingDegree = uieditfield(app.OperatingConditionsPanel, 'numeric');
            app.FillingDegree.Value = 0.15;
            app.FuelselectionLabel_2 = uilabel(app.OperatingConditionsPanel);
            app.FuelselectionLabel_2.Text = 'Fuel selection';
            app.FuelTypeDropDown = uidropdown(app.OperatingConditionsPanel);
            app.FuelTypeDropDown.Items = {'natural gas', 'lpg', 'diesel', 'fuel oil', 'biomass pellets', 'coal'};
            app.FuelTypeDropDown.Value = 'natural gas';
            app.Image = uiimage(app.InputTab);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'icon', 'rotary_dryer_transparent.png');
            app.Image2 = uiimage(app.InputTab);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'icon', 'LOGO.png');
            app.PropertiesTab = uitab(app.TabGroup);
            app.PropertiesTab.SizeChangedFcn = createCallbackFcn(app, @PropertiesTabSizeChanged, true);
            app.PropertiesTab.Title = 'Properties';
            app.MaterialTree = uitree(app.PropertiesTab);
            app.MaterialTree.SelectionChangedFcn = createCallbackFcn(app, @MaterialTreeSelectionChanged, true);
            app.MaterialNode = uitreenode(app.MaterialTree);
            app.MaterialNode.Icon = fullfile(pathToMLAPP, 'icon', 'carpeta.png');
            app.MaterialNode.Text = 'Material';
            app.Lateritic_oreNode = uitreenode(app.MaterialNode);
            app.Lateritic_oreNode.Icon = fullfile(pathToMLAPP, 'icon', 'expediente.png');
            app.Lateritic_oreNode.Text = 'Lateritic_ore';
            app.SolidpropertiesPanel = uipanel(app.PropertiesTab);
            app.SolidpropertiesPanel.Title = 'Solid properties';
            app.BulksoliddensityLabel = uilabel(app.SolidpropertiesPanel);
            app.BulksoliddensityLabel.Text = 'Bulk density';
            app.Bulksdensity = uieditfield(app.SolidpropertiesPanel, 'numeric');
            app.SolidspecificheatcapacityLabel = uilabel(app.SolidpropertiesPanel);
            app.SolidspecificheatcapacityLabel.Text = 'Specific heat capacity';
            app.Cpsolid = uieditfield(app.SolidpropertiesPanel, 'numeric');
            app.SolidthermalconductivityLabel = uilabel(app.SolidpropertiesPanel);
            app.SolidthermalconductivityLabel.Text = 'Thermal conductivity';
            app.Ksolid = uieditfield(app.SolidpropertiesPanel, 'numeric');
            app.kgm3Label = uilabel(app.SolidpropertiesPanel);
            app.kgm3Label.Text = 'kg/m^3';
            app.WmKLabel = uilabel(app.SolidpropertiesPanel);
            app.WmKLabel.Text = 'W/m·K';
            app.JkgKLabel = uilabel(app.SolidpropertiesPanel);
            app.JkgKLabel.Text = 'J/kg·K';
            app.SolidspecificheatcapacityLabel_2 = uilabel(app.SolidpropertiesPanel);
            app.SolidspecificheatcapacityLabel_2.Text = 'Emissivity';
            app.Es = uieditfield(app.SolidpropertiesPanel, 'numeric');
            app.SolidspecificheatcapacityLabel_3 = uilabel(app.SolidpropertiesPanel);
            app.SolidspecificheatcapacityLabel_3.Text = 'True density (ρₜ)';
            app.TrueDensity = uieditfield(app.SolidpropertiesPanel, 'numeric');
            app.SolidspecificheatcapacityLabel_4 = uilabel(app.SolidpropertiesPanel);
            app.SolidspecificheatcapacityLabel_4.Text = 'Mean particle diameter';
            app.ParticleDiameter = uieditfield(app.SolidpropertiesPanel, 'numeric');
            app.kgmLabel = uilabel(app.SolidpropertiesPanel);
            app.kgmLabel.Text = 'kg/m³';
            app.mLabel_4 = uilabel(app.SolidpropertiesPanel);
            app.mLabel_4.Text = 'm';
            app.DeleteButton = uibutton(app.SolidpropertiesPanel, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.DeleteButton.Icon = fullfile(pathToMLAPP, 'icon', 'contenedor.png');
            app.DeleteButton.Text = 'Delete';
            app.SaveButton = uibutton(app.SolidpropertiesPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Icon = fullfile(pathToMLAPP, 'icon', 'Save.png');
            app.SaveButton.Text = 'Save';
            app.Label_6 = uilabel(app.SolidpropertiesPanel);
            app.Label_6.Text = '-';
            app.DryinggaspropertiesPanel = uipanel(app.PropertiesTab);
            app.DryinggaspropertiesPanel.Title = 'Drying gas properties';
            app.SolidspecificheatcapacityLabel_9 = uilabel(app.DryinggaspropertiesPanel);
            app.SolidspecificheatcapacityLabel_9.Text = 'Specific heat capacity';
            app.Cpgas = uieditfield(app.DryinggaspropertiesPanel, 'numeric');
            app.SolidthermalconductivityLabel_2 = uilabel(app.DryinggaspropertiesPanel);
            app.SolidthermalconductivityLabel_2.Text = 'Thermal conductivity';
            app.Kgas = uieditfield(app.DryinggaspropertiesPanel, 'numeric');
            app.WmKLabel_2 = uilabel(app.DryinggaspropertiesPanel);
            app.WmKLabel_2.Text = 'W/m·K';
            app.JkgKLabel_2 = uilabel(app.DryinggaspropertiesPanel);
            app.JkgKLabel_2.Text = 'J/kg·K';
            app.SolidspecificheatcapacityLabel_10 = uilabel(app.DryinggaspropertiesPanel);
            app.SolidspecificheatcapacityLabel_10.Text = 'Emissivity';
            app.Egas = uieditfield(app.DryinggaspropertiesPanel, 'numeric');
            app.SolidspecificheatcapacityLabel_11 = uilabel(app.DryinggaspropertiesPanel);
            app.SolidspecificheatcapacityLabel_11.Text = ' Density ';
            app.Density_gas = uieditfield(app.DryinggaspropertiesPanel, 'numeric');
            app.kgmLabel_2 = uilabel(app.DryinggaspropertiesPanel);
            app.kgmLabel_2.Text = 'kg/m³';
            app.SolidspecificheatcapacityLabel_13 = uilabel(app.DryinggaspropertiesPanel);
            app.SolidspecificheatcapacityLabel_13.Text = 'Dynamic viscosity';
            app.viscosidad_D_gas = uieditfield(app.DryinggaspropertiesPanel, 'numeric');
            app.Gas_Props_cal = uibutton(app.DryinggaspropertiesPanel, 'push');
            app.Gas_Props_cal.Icon = fullfile(pathToMLAPP, 'icon', 'calculadora.png');
            app.Gas_Props_cal.Text = 'Calculate';
            app.PasLabel = uilabel(app.DryinggaspropertiesPanel);
            app.PasLabel.Text = 'Pa·s';
            app.CLabel_5 = uilabel(app.DryinggaspropertiesPanel);
            app.CLabel_5.Text = '°C';
            app.SolidthermalconductivityLabel_7 = uilabel(app.DryinggaspropertiesPanel);
            app.SolidthermalconductivityLabel_7.Text = 'Gas temperature';
            app.Tm_gas = uieditfield(app.DryinggaspropertiesPanel, 'numeric');
            app.Label_5 = uilabel(app.DryinggaspropertiesPanel);
            app.Label_5.Text = '-';
            app.AmbientairPanel = uipanel(app.PropertiesTab);
            app.AmbientairPanel.Title = 'Ambient air';
            app.AmbientTemperatureLabel = uilabel(app.AmbientairPanel);
            app.AmbientTemperatureLabel.Text = 'Ambient temperature ';
            app.AmbientTemperature = uieditfield(app.AmbientairPanel, 'numeric');
            app.AmbientTemperature.Value = 22;
            app.AltitudeEditFieldLabel = uilabel(app.AmbientairPanel);
            app.AltitudeEditFieldLabel.Text = 'Altitude';
            app.Altitude = uieditfield(app.AmbientairPanel, 'numeric');
            app.Altitude.Value = 1;
            app.RelativeHumidityLabel_3 = uilabel(app.AmbientairPanel);
            app.RelativeHumidityLabel_3.Text = 'Relative humidity';
            app.AmbientRelativeHumidity = uieditfield(app.AmbientairPanel, 'numeric');
            app.AmbientRelativeHumidity.Value = 60;
            app.SolidspecificheatcapacityLabel_12 = uilabel(app.AmbientairPanel);
            app.SolidspecificheatcapacityLabel_12.Text = 'Specific heat capacity';
            app.Cp_aire_box = uieditfield(app.AmbientairPanel, 'numeric');
            app.SolidthermalconductivityLabel_3 = uilabel(app.AmbientairPanel);
            app.SolidthermalconductivityLabel_3.Text = 'Thermal conductivity';
            app.K_aire = uieditfield(app.AmbientairPanel, 'numeric');
            app.SolidthermalconductivityLabel_4 = uilabel(app.AmbientairPanel);
            app.SolidthermalconductivityLabel_4.Text = 'Density';
            app.den_aire = uieditfield(app.AmbientairPanel, 'numeric');
            app.CLabel_6 = uilabel(app.AmbientairPanel);
            app.CLabel_6.Text = '°C';
            app.Label_3 = uilabel(app.AmbientairPanel);
            app.Label_3.Text = '%';
            app.mLabel_2 = uilabel(app.AmbientairPanel);
            app.mLabel_2.Text = 'm';
            app.kgkgLabel = uilabel(app.AmbientairPanel);
            app.kgkgLabel.Text = 'kg/kg ';
            app.SolidthermalconductivityLabel_5 = uilabel(app.AmbientairPanel);
            app.SolidthermalconductivityLabel_5.Text = 'Specific humidity';
            app.w_aire = uieditfield(app.AmbientairPanel, 'numeric');
            app.SolidthermalconductivityLabel_6 = uilabel(app.AmbientairPanel);
            app.SolidthermalconductivityLabel_6.Text = 'Enthalpy';
            app.h_aire = uieditfield(app.AmbientairPanel, 'numeric');
            app.WmKLabel_3 = uilabel(app.AmbientairPanel);
            app.WmKLabel_3.Text = 'W/m·K';
            app.airProps_cal = uibutton(app.AmbientairPanel, 'push');
            app.airProps_cal.ButtonPushedFcn = createCallbackFcn(app, @airProps_calButtonPushed, true);
            app.airProps_cal.Icon = fullfile(pathToMLAPP, 'icon', 'calculadora.png');
            app.airProps_cal.Text = 'Calculate';
            app.psicromety = uibutton(app.AmbientairPanel, 'push');
            app.psicromety.ButtonPushedFcn = createCallbackFcn(app, @psicrometyButtonPushed, true);
            app.psicromety.Icon = fullfile(pathToMLAPP, 'icon', 'grafico-de-linea.png');
            app.psicromety.Text = '';
            app.JkgKLabel_3 = uilabel(app.AmbientairPanel);
            app.JkgKLabel_3.Text = 'J/kg·K';
            app.kJkgLabel_2 = uilabel(app.AmbientairPanel);
            app.kJkgLabel_2.Text = 'kJ/kg';
            app.kgmLabel_3 = uilabel(app.AmbientairPanel);
            app.kgmLabel_3.Text = 'kg/m³';
            app.KineticModelFittingTab = uitab(app.TabGroup);
            app.KineticModelFittingTab.Title = 'Kinetic Model Fitting';
            app.ArrheniusAxes = uiaxes(app.KineticModelFittingTab);
            xlabel(app.ArrheniusAxes, 'X')
            ylabel(app.ArrheniusAxes, 'Y')
            zlabel(app.ArrheniusAxes, 'Z')
            app.SolidspecificheatcapacityLabel_14 = uilabel(app.KineticModelFittingTab);
            app.SolidspecificheatcapacityLabel_14.Text = 'R (J/mol·K)';
            app.paramet_R = uieditfield(app.KineticModelFittingTab, 'numeric');
            app.SolidspecificheatcapacityLabel_15 = uilabel(app.KineticModelFittingTab);
            app.SolidspecificheatcapacityLabel_15.Text = 'A (1/s)';
            app.paramet_A = uieditfield(app.KineticModelFittingTab, 'numeric');
            app.BulksoliddensityLabel_3 = uilabel(app.KineticModelFittingTab);
            app.BulksoliddensityLabel_3.Text = 'E (kJ/mol)';
            app.paramet_E = uieditfield(app.KineticModelFittingTab, 'numeric');
            app.ModelDropDown = uidropdown(app.KineticModelFittingTab);
            app.ModelDropDown.Items = {'Newton', 'Page', 'Modified Page', 'Henderson-Pabis', 'Logarithmic', 'Two-Term', 'Wang-Singh', 'Midilli-Kucuk'};
            app.ModelDropDown.ValueChangedFcn = createCallbackFcn(app, @ModelDropDownValueChanged, true);
            app.ModelDropDown.Value = 'Newton';
            app.BulksoliddensityLabel_2 = uilabel(app.KineticModelFittingTab);
            app.BulksoliddensityLabel_2.Text = 'Xeq (kg/kg d.b.)';
            app.paramet_Heq = uieditfield(app.KineticModelFittingTab, 'numeric');
            app.KineticEquationLabel = uilabel(app.KineticModelFittingTab);
            app.KineticEquationLabel.Text = 'Model';
            app.Impor_data = uibutton(app.KineticModelFittingTab, 'push');
            app.Impor_data.ButtonPushedFcn = createCallbackFcn(app, @Impor_dataButtonPushed, true);
            app.Impor_data.Icon = fullfile(pathToMLAPP, 'icon', 'importar.png');
            app.Impor_data.Text = 'Import';
            app.FitKineticsButton = uibutton(app.KineticModelFittingTab, 'push');
            app.FitKineticsButton.ButtonPushedFcn = createCallbackFcn(app, @FitKineticsButtonPushed, true);
            app.FitKineticsButton.Icon = fullfile(pathToMLAPP, 'icon', 'grafico-de-linea.png');
            app.FitKineticsButton.Text = 'Fit Kinetics';
            app.KineticTable = uitable(app.KineticModelFittingTab);
            app.KineticTable.ColumnName = {'Model'; 'T (°C)'; 'k'; 'k1'; 'k2'; 'a'; 'b'; 'c'; 'n'; 'Xeq'; 'RMSE'; 'R2'};
            app.KineticTable.ColumnRearrangeable = 'on';
            app.KineticTable.RowName = {};
            app.KineticTable.ColumnEditable = [false true];
            app.FitArrheniusButton = uibutton(app.KineticModelFittingTab, 'push');
            app.FitArrheniusButton.ButtonPushedFcn = createCallbackFcn(app, @FitArrheniusButtonPushed, true);
            app.FitArrheniusButton.Icon = fullfile(pathToMLAPP, 'icon', 'grafico-de-linea.png');
            app.FitArrheniusButton.Text = 'Fit Arrhenius';
            app.AddrowButton = uibutton(app.KineticModelFittingTab, 'push');
            app.AddrowButton.ButtonPushedFcn = createCallbackFcn(app, @AddrowButtonPushed, true);
            app.AddrowButton.Icon = fullfile(pathToMLAPP, 'icon', 'agregar.png');
            app.AddrowButton.Text = 'Add row';
            app.KineticTable_2 = uitable(app.KineticModelFittingTab);
            app.KineticTable_2.ColumnName = {'Temperature (°C)'; 'Time (s)'; 'Moisture (kg/kg d.b.)'};
            app.KineticTable_2.RowName = {};
            app.ResultsTab = uitab(app.TabGroup);
            app.ResultsTab.Title = 'Results';
            app.ButtonGroup = uibuttongroup(app.ResultsTab);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.UIAxes = uiaxes(app.ButtonGroup);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.TempButton = uiradiobutton(app.ButtonGroup);
            app.TempButton.Text = 'Temperature';
            app.TempButton.Value = true;
            app.HumidityButton = uiradiobutton(app.ButtonGroup);
            app.HumidityButton.Text = 'Solid moisture';
            app.UITableResults = uitable(app.ButtonGroup);
            app.UITableResults.ColumnName = {'Parameter'; 'Value'; 'Unit'};
            app.UITableResults.RowName = {};
            app.T_x_M = uiradiobutton(app.ButtonGroup);
            app.T_x_M.Text = 'Temperature and solid moisture';
            app.exportfig = uibutton(app.ButtonGroup, 'push');
            app.exportfig.ButtonPushedFcn = createCallbackFcn(app, @exportfigButtonPushed, true);
            app.exportfig.Icon = fullfile(pathToMLAPP, 'icon', 'exportar.png');
            app.exportfig.Text = '';
            app.exporttable = uibutton(app.ButtonGroup, 'push');
            app.exporttable.ButtonPushedFcn = createCallbackFcn(app, @exporttableButtonPushed, true);
            app.exporttable.Icon = fullfile(pathToMLAPP, 'icon', 'exportar.png');
            app.exporttable.Text = '';
            app.DesignparametersPanel = uipanel(app.ResultsTab);
            app.DesignparametersPanel.Title = 'Design parameters';
            app.DinameterLabel = uilabel(app.DesignparametersPanel);
            app.DinameterLabel.Text = 'Drum diameter';
            app.Diametro_test = uieditfield(app.DesignparametersPanel, 'numeric');
            app.kgsLabel = uilabel(app.DesignparametersPanel);
            app.kgsLabel.Text = 'kg/s';
            app.FgasLabel_4 = uilabel(app.DesignparametersPanel);
            app.FgasLabel_4.Text = 'Dry-gas mass flow rate';
            app.RateMgs = uieditfield(app.DesignparametersPanel, 'numeric');
            app.mLabel_3 = uilabel(app.DesignparametersPanel);
            app.mLabel_3.Text = 'm';
            app.DesignButton = uibutton(app.DesignparametersPanel, 'push');
            app.DesignButton.ButtonPushedFcn = createCallbackFcn(app, @DesignButtonPushed, true);
            app.DesignButton.Icon = fullfile(pathToMLAPP, 'icon', 'calculadora.png');
            app.DesignButton.Text = 'Design';
            app.SolverparametersPanel = uipanel(app.ResultsTab);
            app.SolverparametersPanel.Title = 'Solver parameters';
            app.RelativeHumidityLabel_4 = uilabel(app.SolverparametersPanel);
            app.RelativeHumidityLabel_4.Text = 'Axial step size (m)';
            app.Stip = uieditfield(app.SolverparametersPanel, 'numeric');
            app.Stip.Value = 0.01;
            app.LmaxLabel = uilabel(app.SolverparametersPanel);
            app.LmaxLabel.Text = 'Max axial length Lmax (m)';
            app.Lm_ax = uieditfield(app.SolverparametersPanel, 'numeric');
            app.Lm_ax.Value = 60;
            app.ToleranceLabel = uilabel(app.SolverparametersPanel);
            app.ToleranceLabel.Text = 'Moisture tolerance (kg/kg d.b.) ';
            app.Tolerance = uieditfield(app.SolverparametersPanel, 'numeric');
            app.Tolerance.Value = 1e-06;
            app.SolveButton = uibutton(app.SolverparametersPanel, 'push');
            app.SolveButton.ButtonPushedFcn = createCallbackFcn(app, @SolveButtonPushed, true);
            app.SolveButton.Icon = fullfile(pathToMLAPP, 'icon', 'tocar.png');
            app.SolveButton.Text = 'Solve';
            app.StatusPanel = uipanel(app.ResultsTab);
            app.StatusPanel.Title = 'Status';
            app.SolverstatusLampLabel = uilabel(app.StatusPanel);
            app.SolverstatusLampLabel.Text = 'Solver status';
            app.SolverstatusLamp = uilamp(app.StatusPanel);
            app.TargetreachedLampLabel = uilabel(app.StatusPanel);
            app.TargetreachedLampLabel.Text = 'Target reached';
            app.TargetreachedLamp = uilamp(app.StatusPanel);
            app.PhysicalvalidityLampLabel = uilabel(app.StatusPanel);
            app.PhysicalvalidityLampLabel.Text = 'Physical validity';
            app.PhysicalvalidityLamp = uilamp(app.StatusPanel);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RotaryDryerDesigner

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end